classdef AddEditApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AddEditDeleteButtonGroup  matlab.ui.container.ButtonGroup
        AddButton                 matlab.ui.control.ToggleButton
        EditButton                matlab.ui.control.ToggleButton
        DeleteButton              matlab.ui.control.ToggleButton
        NumEditFieldLabel         matlab.ui.control.Label
        NumEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ThetaEditFieldLabel       matlab.ui.control.Label
        ThetaEditField            matlab.ui.control.NumericEditField
        BPYEditFieldLabel         matlab.ui.control.Label
        BPYEditField              matlab.ui.control.NumericEditField
        InputvalidXYandthetadeginnumericvalueLabel  matlab.ui.control.Label
        SelectTypeandBPZoneNoneifnotinazoneLabel  matlab.ui.control.Label
        InputvalidBPinformatLetterColNumberRowLabel  matlab.ui.control.Label
        BPZoneDropDownLabel       matlab.ui.control.Label
        BPZoneDropDown            matlab.ui.control.DropDown
        DoneButton                matlab.ui.control.Button
        TypeDropDownLabel         matlab.ui.control.Label
        TypeDropDown              matlab.ui.control.DropDown
        BPXEditFieldLabel         matlab.ui.control.Label
        BPXEditField              matlab.ui.control.NumericEditField
        AutoXYButton              matlab.ui.control.StateButton
    end


    properties (Access = private)
        % Calling app
        CallingApp;
        
        % Do set bp
        AutoXY;
        
        % Data
        Edit;   % 1 for edit, 0 for add
        Number;
        X;
        Y;
        Theta;
        Type;
        BPZone;
        BPX;
        BPY;
    end

    methods (Access = private)
    
        function SendBackToGUI(app)
            % Prepare the data struct to be returned to the main app
            Data.Edit = app.Edit;
            Data.Number = app.Number;
            Data.X = app.X;
            Data.Y = app.Y;
            Data.Theta = app.Theta;
            Data.Type = app.Type;
            Data.BPZone = app.BPZone;
            Data.BPX = app.BPX;
            Data.BPY = app.BPY;
            
            % Send to the main app
            ReceiveAddEditData(app.CallingApp, Data);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % Store the main app
            app.CallingApp = MainApp;
            
            % Disable the num edit
            app.NumEditField.Enable = 'off';

            app.BPXEditField.Enable = 'off';
            app.BPYEditField.Enable = 'off';
            
            app.AutoXYButton.Value = 1;
        end

        % Selection changed function: AddEditDeleteButtonGroup
        function AddEditDeleteButtonGroupSelectionChanged(app, event)
            selectedButton = app.AddEditDeleteButtonGroup.SelectedObject;
            % If it edit, enable the number button
            if (strcmp(selectedButton.Text, 'Edit'))
                app.NumEditField.Enable = 'on';
                app.BPXEditField.Enable = 'off';
                app.BPYEditField.Enable = 'off';
                app.ThetaEditField.Enable = 'on';
                app.XEditField.Enable = 'on';
                app.YEditField.Enable = 'on';
                app.TypeDropDown.Enable = 'on';
            elseif (strcmp(selectedButton.Text, 'Add'))
                app.NumEditField.Enable = 'off';
                app.BPXEditField.Enable = 'on';
                app.BPYEditField.Enable = 'on';
                app.ThetaEditField.Enable = 'on';
                app.XEditField.Enable = 'on';
                app.YEditField.Enable = 'on';
                app.TypeDropDown.Enable = 'on';
            else
                app.NumEditField.Enable = 'on';
                app.BPXEditField.Enable = 'off';
                app.BPYEditField.Enable = 'off';
                app.ThetaEditField.Enable = 'off';
                app.XEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
                app.TypeDropDown.Enable = 'off';
            end
            BPZoneDropDownValueChanged(app,event);
            AutoXYButtonValueChanged(app, event);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if (app.AddButton.Value == 1)
                app.Edit = 0;   %ADD
            elseif (app.EditButton.Value == 1)
                app.Edit = 1;   %EDIT
            elseif(app.DeleteButton.Value == 1)
                app.Edit = 2;   %DELETE
            end
            %app.Edit = app.AddEditDeleteButtonGroup
            app.Number = app.NumEditField.Value;
            app.Theta = degtorad(app.ThetaEditField.Value);
            app.BPX = app.BPXEditField.Value;
            app.BPY = app.BPYEditField.Value;
            
            if (strcmp(app.TypeDropDown.Value, 'Letter'))
                app.Type = 0;
            else
                app.Type = 1;
            end
            
            if (strcmp(app.BPZoneDropDown.Value, 'None'))
                app.BPZone = 0;
                app.X = app.XEditField.Value;
                app.Y = app.YEditField.Value;
            elseif (strcmp(app.BPZoneDropDown.Value, 'P1'))
                app.BPZone = 1;
                if (app.AutoXY)
                    [app.X, app.Y] = BPtoCoord('P1', int2str(app.BPX), int2str(app.BPY));
                else
                    app.X = app.XEditField.Value;
                    app.Y = app.YEditField.Value;
                    app.BPX
                end
            elseif (strcmp(app.BPZoneDropDown.Value, 'P2'))
                app.BPZone = 2;
                if (app.AutoXY)
                    [app.X, app.Y] = BPtoCoord('P2', int2str(app.BPX), int2str(app.BPY));
                else
                    app.X = app.XEditField.Value;
                    app.Y = app.YEditField.Value;
                end
            else
                app.BPZone = 3;
                if (app.AutoXY)
                    [app.X, app.Y] = BPtoCoord('main', int2str(app.BPX), int2str(app.BPY));
                else
                    app.X = app.XEditField.Value;
                    app.Y = app.YEditField.Value;
                end
            end
            
            SendBackToGUI(app);
        end

        % Value changed function: BPZoneDropDown
        function BPZoneDropDownValueChanged(app, event)
            value = app.BPZoneDropDown.Value;
            
            if (~strcmp(value, 'None'))
                app.XEditField.Enable = 'Off';
                app.YEditField.Enable = 'Off';
                app.BPXEditField.Enable = 'on';
                app.BPYEditField.Enable = 'on';
            else
                app.XEditField.Enable = 'On';
                app.YEditField.Enable = 'On';
                app.BPXEditField.Enable = 'off';
                app.BPYEditField.Enable = 'off';
            end
            
            if (strcmp(app.AddEditDeleteButtonGroup.SelectedObject.Text, 'Delete'))
                app.XEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
                app.BPXEditField.Enable = 'off';
                app.BPYEditField.Enable = 'off';
            end
            AutoXYButtonValueChanged(app, event);
        end

        % Value changed function: AutoXYButton
        function AutoXYButtonValueChanged(app, event)
            value = app.AutoXYButton.Value;
            if ((app.AddButton.Value == 1 || app.EditButton.Value == 1) && ~strcmp(app.BPZoneDropDown.Value, 'None'))
                app.AutoXY = value;
                if (value == 0)
                    app.XEditField.Enable = 'on';
                    app.YEditField.Enable = 'on';
                else
                    app.XEditField.Enable = 'off';
                    app.YEditField.Enable = 'off';
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AddEditDeleteButtonGroup = uibuttongroup(app.UIFigure);
            app.AddEditDeleteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AddEditDeleteButtonGroupSelectionChanged, true);
            app.AddEditDeleteButtonGroup.Title = 'Add - Edit - Delete';
            app.AddButton = uitogglebutton(app.AddEditDeleteButtonGroup);
            app.AddButton.Text = 'Add';
            app.AddButton.Value = true;
            app.EditButton = uitogglebutton(app.AddEditDeleteButtonGroup);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uitogglebutton(app.AddEditDeleteButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.NumEditFieldLabel = uilabel(app.UIFigure);
            app.NumEditFieldLabel.Text = 'Num';
            app.NumEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumEditField.Limits = [1 81];
            app.NumEditField.ValueDisplayFormat = '%.0f';
            app.NumEditField.Value = 1;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Limits = [1 1600];
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Limits = [1 1200];
            app.YEditField.Value = 1;
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaEditField.Limits = [-90 90];
            app.BPYEditFieldLabel = uilabel(app.UIFigure);
            app.BPYEditFieldLabel.Text = 'BP-Y';
            app.BPYEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPYEditField.Limits = [1 9];
            app.BPYEditField.ValueDisplayFormat = '%.0f';
            app.BPYEditField.Value = 1;
            app.InputvalidXYandthetadeginnumericvalueLabel = uilabel(app.UIFigure);
            app.InputvalidXYandthetadeginnumericvalueLabel.Text = 'Input valid X, Y and theta (deg) in numeric value';
            app.SelectTypeandBPZoneNoneifnotinazoneLabel = uilabel(app.UIFigure);
            app.SelectTypeandBPZoneNoneifnotinazoneLabel.Text = 'Select Type and BP Zone (None if not in a zone)';
            app.InputvalidBPinformatLetterColNumberRowLabel = uilabel(app.UIFigure);
            app.InputvalidBPinformatLetterColNumberRowLabel.Text = 'Input valid BP in format Letter = Col, Number = Row ';
            app.BPZoneDropDownLabel = uilabel(app.UIFigure);
            app.BPZoneDropDownLabel.Text = 'BP Zone';
            app.BPZoneDropDown = uidropdown(app.UIFigure);
            app.BPZoneDropDown.Items = {'None', 'P1', 'P2', 'Board'};
            app.BPZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @BPZoneDropDownValueChanged, true);
            app.BPZoneDropDown.Value = 'None';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Letter', 'Shape'};
            app.TypeDropDown.Value = 'Letter';
            app.BPXEditFieldLabel = uilabel(app.UIFigure);
            app.BPXEditFieldLabel.Text = 'BP-X';
            app.BPXEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPXEditField.Limits = [1 9];
            app.BPXEditField.ValueDisplayFormat = '%.0f';
            app.BPXEditField.Value = 1;
            app.AutoXYButton = uibutton(app.UIFigure, 'state');
            app.AutoXYButton.ValueChangedFcn = createCallbackFcn(app, @AutoXYButtonValueChanged, true);
            app.AutoXYButton.Text = 'Auto XY';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AddEditApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end