classdef GUIV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Assignment3AppUIFigure    matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        OverviewTab               matlab.ui.container.Tab
        BoardBlockList            matlab.ui.control.Table
        InsertBoxButton           matlab.ui.control.Button
        ReloadBoxButton           matlab.ui.control.Button
        ConveyorLabel             matlab.ui.control.Label
        BoardBlockListLabel       matlab.ui.control.Label
        EditAddButton             matlab.ui.control.Button
        UpdateTableButton         matlab.ui.control.Button
        UpdateConveyorButton      matlab.ui.control.Button
        VisionUpdateLabel         matlab.ui.control.Label
        P1BlockList               matlab.ui.control.Table
        P2BlockList               matlab.ui.control.Table
        P1BlockListLabel          matlab.ui.control.Label
        P2BlockListLabel          matlab.ui.control.Label
        TableBlockListLabel_2     matlab.ui.control.Label
        TableBlockList            matlab.ui.control.Table
        Table                     matlab.ui.container.Tab
        TabVidAxes                matlab.ui.control.UIAxes
        ConveyorTab               matlab.ui.container.Tab
        ConVidAxes                matlab.ui.control.UIAxes
        SimpleMoveTab             matlab.ui.container.Tab
        XEditFieldLabel           matlab.ui.control.Label
        XTabH                     matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YTabH                     matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZTabH                     matlab.ui.control.NumericEditField
        TabHSend                  matlab.ui.control.Button
        RelativeToTableHomeLabel  matlab.ui.control.Label
        ComplexMoveTab            matlab.ui.container.Tab
        TicTacToeTab              matlab.ui.container.Tab
        PathPlanTab               matlab.ui.container.Tab
        DebugTab                  matlab.ui.container.Tab
        MainTickLampLabel         matlab.ui.control.Label
        MainTickLamp              matlab.ui.control.Lamp
    end


    properties (Access = private)
        
        % Timer used for tick callback fucntion
        tickTimer;
        tickPeriod = 0.5; % Period of tick timer
        
        % Video objects and handles
        vidTable;               % videoinput handle
        vidConveyor;            % videoinput handle
        TabVidHandle;   % imshow handle
        ConVidHandle;   % imshow handle
        
        % the commandQueue and communications
        queue = [];
        robotBusy = 0;
        
        socket = 0;
        connectType = 2;
        % Error flags
        genErr = 0;
        commError = 0;
        initFlag = 0;

        
        % Toggle set in tick (debug only)
        tickToggle;
        tickToggle2;
        
        % Data arrays for player 1, 2, Board and table
        P1Data;
        P2Data;
        BoardData;
        TableData;
        P1Num;  % Current num blocks in P1 deck
        P2Num;  % "
        BoardNum; % Current num blocks on board
        TableNum;
        
        BoardBlockVisionList = [];
        P1BlockVisionList = [];
        P2BlockVisionList = [];
        
        % Display handles
        tabCentroidHandle;
        tabAngleHandle;
        conCentroidHandle;
        conGridHandle;
        
        % Conveyor
        conGridX = [];
        conGridY = [];
        
    end

    methods (Access = private)
    
        function InitVideoFeeds(app)
          % Init the video feeds
             try %create the table camera feed
                % Prep video object for manual and start it
                hold(app.TabVidAxes, 'on');  
                app.vidTable = videoinput('winvideo', 1, 'MJPG_1600x1200');
                %app.vidTable.ReturnedColorSpace = 'rgb';
                triggerconfig(app.vidTable, 'manual');
                %src1 = getselectedsource(app.vidTable);
                %src1.ExposureMode = 'manual';
                %src1.Exposure = -4;
                start(app.vidTable);
                % Prep axis handle
                video_resolution1 = app.vidTable.VideoResolution;
                nbands1 = app.vidTable.NumberOfBands;
                app.TabVidHandle = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.TabVidAxes);
                
             catch %if theres no camera, then use an image
                 disp('FROM VIDEOFEEDINIT: No tab camera - using static images');
                 hold(app.TabVidAxes, 'on');
                 app.TabVidHandle = imshow(zeros(1200,1600), 'parent', app.TabVidAxes);
             end
            
            % create tab image handles for plots

            app.tabCentroidHandle = plot(app.TabVidAxes,0,0, 'g+');
            %app.tabAngleHandle = line(app.TabVidAxes,0,0, 'b');
            hold(app.TabVidAxes, 'off');     
           
            try
                 Prep video object for manual and start it
                 app.vidConveyor = videoinput('winvideo', 2, 'MJPG_1600x1200');
                 app.vidConveyor.ReturnedColorSpace = 'rgb';
                 triggerconfig(app.vidConveyor, 'manual');
                 start(app.vidConveyor);
                 Prep axis handle
                 video_resolution2 = app.vidConveyor.VideoResolution;
                 nbands2 = app.vidConveyor.NumberOfBands;
                 hold(app.ConVidAxes, 'on');
                 app.ConVidHandle = imshow(zeros([video_resolution2(2), video_resolution2(1), nbands2]), 'Parent', app.ConVidAxes);  
                            
            catch
                disp('FROM VIDEOFEEDINIT: No con camera - using static images');
                hold(app.ConVidAxes, 'on');  
                app.ConVidHandle = imshow(zeros(1200,1600), 'parent', app.ConVidAxes);
            end
            %conveyer plot handle
            app.conCentroidHandle = plot(app.ConVidAxes,0,0, 'g+');
            app.conGridHandle(1) = plot(app.ConVidAxes,0,0,'b');
            app.conGridHandle(2) = plot(app.ConVidAxes,0,0,'b');
            
            hold(app.ConVidAxes, 'off');
                    
        end
        
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        % Tick Callback function (MAIN LOOP)
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function MainTickCallback(app,~,~)
            
%             if (~app.initFlag) && (~app.commError)
%                 % Connection case for first loop of connection (icluding being disconnected)
%                 % Initialization:
%                 [app.socket, app.connectType] = startConnectionToRobot();
%                 
%                 %if no proper connection, raise error
%                 if app.connectType == 2 %error
%                     app.commError = 1;
%                     app.initFlag = 1;
%                     disp('FROM MAIN: Initial Connection Failed')
%                 else
%                     app.commError = 0;
%                     app.initFlag = 1;
%                 end
%                 
%             end
%             
%             % Subseuqent loops
%             %----------------------------------
%             if (app.initFlag) && ~app.commError
%                 % normal connection
%                 % send the message on the queue to the robot and recieve a reply
%                 [app.queue, out, app.commError] = sendToRobot(app.queue, app.socket, app.genErr, app.robotBusy);
% 
%                 if ~app.commError && ~isempty(out)
%                     %process the message if there isn't a socket error
%                     % if there is a socket error then hold off because there isnt actually a message
%                     app.robotBusy = processMessage(out);
%                 end 
%                 
%             elseif (app.initFlag) && app.commError
%                 % lost connection halfway through
%                 % ask if you wana reconnect
%                 
%                 disp('FROM MAIN: Connection Lost');
%                 % Popup a connection error, if reconnect then go back to the init loop
%                 app.commError = connectionPopUp();
%                 app.commError = 1 - app.commError; %just to invert the answer i.e. yes = 0;
%                 
%                 app.initFlag = 0;
%                 
%             elseif (app.initFlag == 0) && app.commError
%                 % if they answered no
%                 app.Assignment3AppUIFigureCloseRequest();
%                 disp('FROM MAIN: DYING')
%             end
            
            
            % For debugging (flashes lamp on debug page)
            if (app.tickToggle == 1)
                app.MainTickLamp.Color = 'white';
                app.tickToggle = 0;
            else
                app.MainTickLamp.Color = 'green';
                app.tickToggle = 1;
            end
                        
            % Update the GUI lists based on data array
            UpdateDataLists(app);
            
            % Update the image axes based on data array
            %disp('A')
            UpdateImagePlot(app);
        end
        
        % Hard close app
        function AppClose(app)
            disp("~~~~~ APP CLOSING ~~~~~");
            CloseConnection(app);
            StopTimers(app);
            StopVideos(app);
            %CloseFigs(app);
            delete(app);
        end
        
        % Close socket connection
        function CloseConnection(app)
            try
                if app.socket ~= 0
                    closeConnection(app.socket);         
                end
                
            catch
                disp('Socket close failed');
            end
        end
        
        % Function for gracefully kill all timers. (Thanks to the god that is Image Analyst on mathworks forums)
        function StopTimers(app)
            try
            	%fprintf('Entering StopTimer...\n');
            	listOfTimers = timerfindall; % List all timers, just for info.
            	% Get handle to the one timer that we should have.
            	if isempty(listOfTimers)
            		% Exit if there is no timer to turn off.
            		%fprintf('There are no timers to turn off. Leaving StopTimer().\n');
            		return;
            	end
            	% Stop that timer.
            	stop(app.tickTimer);
                delete(app.tickTimer);
            	% Delete all timers from memory.
            	listOfTimers = timerfindall;
            	if ~isempty(listOfTimers)
                        stop(listOfTimers(:));
            		delete(listOfTimers(:));
            	end
            	%fprintf('Left StopTimer and turned off all timers.\n');
            catch ME
            	errorMessage = sprintf('Error in StopTimer().\nThe error reported by MATLAB is:\n\n%s', ME.message);
            	fprintf('%s\n', errorMessage);
            end
        end
        
        % Stop the video by reset
        function StopVideos(app)
            imaqreset;
            %stop(app.vidTable);
            %stop(app.vidConveyor);

        end
        
        % Close all figs
        function CloseFigs(app)
            
        end
        
        function InitDataArrays(app)
            app.P1Data = NaN(6,1,6);      % Player decks are 1x6x6
            app.P2Data = NaN(6,1,6);
            app.BoardData = NaN(9,9,6);    % Board deck is 9x9x6
            app.P1Num = 0;
            app.P2Num = 0;
            app.BoardNum = 0;
        end
        
        % Add or edit player data
        function PlayerDataAddEdit(app, Player, Data)
            if (Player == 1)
                if (Data.Edit == 1) % Editing
                    % Get the block pos based off the number
                    [BPY, BPX] = GetBlockPos(Data.Number, app.P1Data);
                    if (BPY == -1)
                        return;
                    end 
                    % Check it is occupied
                    if (app.P1Data(BPY, BPX, 1) ~= 1)
                        return;
                    else
                        % Fill the data
                        app.P1Data(BPY, BPX, 1) = 1;
                        app.P1Data(BPY, BPX, 2) = Data.X;
                        app.P1Data(BPY, BPX, 3) = Data.Y;
                        app.P1Data(BPY, BPX, 4) = Data.Theta;
                        app.P1Data(BPY, BPX, 5) = Data.Type;
                        app.P1Data(BPY, BPX, 6) = Data.Number;
                    end
                else    % Adding
                    % Check if it is occupied
                    if (app.P1Data(Data.BPY, Data.BPX, 1) == 1)
                        return;
                    else
                        % Fill the data
                        app.P1Data(Data.BPY, Data.BPX, 1) = 1;
                        app.P1Data(Data.BPY, Data.BPX, 2) = Data.X;
                        app.P1Data(Data.BPY, Data.BPX, 3) = Data.Y;
                        app.P1Data(Data.BPY, Data.BPX, 4) = Data.Theta;
                        app.P1Data(Data.BPY, Data.BPX, 5) = Data.Type;
                        app.P1Data(Data.BPY, Data.BPX, 6) = app.P1Num + 1;
                        app.P1Num = app.P1Num + 1;  % Increment counter
                    end
                end
            elseif (Player == 2)
                if (Data.Edit == 1) % Editing
                    % Get the block pos based off the number
                    [BPY, BPX] = GetBlockPos(Data.Number, app.P2Data);
                    if (BPY == -1)
                        return;
                    end
                    % Check it is occupied
                    if (app.P2Data(BPY, BPX, 1) ~= 1)
                        return;
                    else
                        % Fill the data
                        app.P2Data(BPY, BPX, 1) = 1;
                        app.P2Data(BPY, BPX, 2) = Data.X;
                        app.P2Data(BPY, BPX, 3) = Data.Y;
                        app.P2Data(BPY, BPX, 4) = Data.Theta;
                        app.P2Data(BPY, BPX, 5) = Data.Type;
                        app.P2Data(BPY, BPX, 6) = Data.Number;
                    end
                else    % Adding
                    % Check if it is occupied
                    if (app.P2Data(Data.BPY, Data.BPX,1) == 1)
                        return;
                    else
                        % Fill the data
                        app.P2Data(Data.BPY, Data.BPX, 1) = 1;
                        app.P2Data(Data.BPY, Data.BPX, 2) = Data.X;
                        app.P2Data(Data.BPY, Data.BPX, 3) = Data.Y;
                        app.P2Data(Data.BPY, Data.BPX, 4) = Data.Theta;
                        app.P2Data(Data.BPY, Data.BPX, 5) = Data.Type;
                        app.P2Data(Data.BPY, Data.BPX, 6) = app.P2Num + 1;
                        app.P2Num = app.P2Num + 1;  % Increment counter
                    end
                end
            end
        end
        
        function BoardDataAddEdit(app, Data)
            if (Data.Edit == 1) % Editing
                % Get the block pos based off the number
                [BPY, BPX] = GetBlockPos(Data.Number, app.BoardData);
                if (BPY == -1)
                    return;
                end 
                if (app.BoardData(BPY, BPX, 1) ~= 1)
                    return;
                else
                    % Fill the data
                    app.BoardData(BPY, BPX, 1) = 1;
                    app.BoardData(BPY, BPX, 2) = Data.X;
                    app.BoardData(BPY, BPX, 3) = Data.Y;
                    app.BoardData(BPY, BPX, 4) = Data.Theta;
                    app.BoardData(BPY, BPX, 5) = Data.Type;
                    app.BoardData(BPY, BPX, 6) = Data.Number;
                end
            else    % Adding
                % Check if it is occupied
                if (app.BoardData(Data.BPY, Data.BPX,1) == 1)
                    return;
                else
                    % Fill the data
                    app.BoardData(Data.BPY, Data.BPX, 1) = 1;
                    app.BoardData(Data.BPY, Data.BPX, 2) = Data.X;
                    app.BoardData(Data.BPY, Data.BPX, 3) = Data.Y;
                    app.BoardData(Data.BPY, Data.BPX, 4) = Data.Theta;
                    app.BoardData(Data.BPY, Data.BPX, 5) = Data.Type;
                    app.BoardData(Data.BPY, Data.BPX, 6) = app.BoardNum + 1;
                    app.BoardNum = app.BoardNum + 1;  % Increment counter
                end
            end
        end
        
        function TableDataAddEdit(app, Data)
            
        end
        
        function UpdateDataLists(app)
            % P1
            PrepP1Data = PrepareData(app.P1Num, app.P1Data);
            app.P1BlockList.Data = PrepP1Data;
            
            % P2
            PrepP2Data = PrepareData(app.P2Num, app.P2Data);
            app.P2BlockList.Data = PrepP2Data;
            
            % Board
            PrepBoardData = PrepareData(app.BoardNum, app.BoardData);
            app.BoardBlockList.Data = PrepBoardData;
            
        end
        
        function UpdateImagePlot(app)
            hold(app.TabVidAxes,'on');
            hold(app.ConVidAxes,'on');

            % P1
            PrepP1ImData = PrepareImageData(app.P1Num, app.P1Data);
            % P2
            PrepP2ImData = PrepareImageData(app.P2Num, app.P2Data);
            % Board
            PrepBoardImData = PrepareImageData(app.BoardNum, app.BoardData);
            % Off the table
            % ~~~~
            
            totalData = [PrepP1ImData; PrepP2ImData; PrepBoardImData]; 
            set(app.tabCentroidHandle, 'xdata', totalData(:,1), 'ydata', totalData(:,2));

            [angleVecsX, angleVecsY] = pol2cart(totalData(:,3), 50);
            angleVecsX = angleVecsX + totalData(:,1);
            angleVecsY = angleVecsY + totalData(:,2);
         
            for i = 1:size(totalData, 1)
                xy = [totalData(i,1:2); angleVecsX(i) angleVecsY(i);];
                plot(app.TabVidAxes, xy(:,1), xy(:,2),'Color','blue');
            end
            
            hold(app.TabVidAxes,'off')
            hold(app.ConVidAxes,'off');

%             app.tabAngleHandle;
%             app.conCentroidHandle;
        end
        
        function MainTickErrorCallback(app,a,b)

        end
        
    end

    methods (Access = public)
        % Public function to receive data from external input app
        function ReceiveAddEditData(app, Data)
            if (Data.BPZone == 1 || Data.BPZone == 2)
                PlayerDataAddEdit(app, Data.BPZone, Data)
            elseif (Data.BPZone == 3)
                BoardDataAddEdit(app, Data)
            else
                TableDataAddEdit(app, Data)
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imaqreset;
            pause(0.5);
            %Flag initialization
            app.genErr = 0;
            app.commError = 0;
            app.initFlag = 0;
            
            % Start the video feeds and prep handles
            fprintf('\n\n\nINITIALIZING ... ');
                        
            %try
                InitVideoFeeds(app);
            %catch
                %disp('Error in init video feed');
            %send           
            
            % Init the timer for the tick callback function
            app.tickTimer = timer('Period', app.tickPeriod,'ExecutionMode', 'fixedRate', ...
                                  'TasksToExecute', Inf,'BusyMode','drop','StartDelay', 2);
             
            app.tickTimer.TimerFcn = @(obj,event)app.MainTickCallback(app);
            %app.tickTimer.StopFcn = @(obj,event)app.stopTickCallback(app);
            app.tickTimer.ErrorFcn = @(obj,event)app.MainTickErrorCallback(app);
            
            start(app.tickTimer);            
            
            % Init the tick toggle
            app.tickToggle = 0;

            % Init the player and board data arrays
            InitDataArrays(app);
        end

        % Button pushed function: TabHSend
        function TabHSendPushed(app, event)
            % X,Y,Z relative move to table home
            x = app.XTabH.Value;
            y = app.YTabH.Value;
            z = app.ZTabH.Value;

            
        end

        % Close request function: Assignment3AppUIFigure
        function Assignment3AppUIFigureCloseRequest(app, event)
            AppClose(app);
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
%             try
                % 1. Get the snapshot --------------------
                %imageTab = getsnapshot(app.vidTable);
                imageTab = imread('tabIm5.jpg');
                % Show it in the correct axes
                set(app.TabVidHandle, 'CData', imageTab); 

                
                % 2. Process the image for blocks ---------------------------
                blocks = detectBlock(imageTab);
                
                % 3. BPs - populate the occupancy grid and decks based on block centroids ----
                [app.BoardBlockVisionList, VisBoardData, irOG] = centroidToBoardBP(blocks);
                [app.P1BlockVisionList, VisP1Data, irP1] = centroidToP1Deck(blocks);
                [app.P2BlockVisionList, VisP2Data, irP2] = centroidToP2Deck(blocks);
                app.BoardNum = max(max(VisBoardData(:,:,6)));
                app.P1Num = max(max(VisP1Data(:,:,6)));
                app.P2Num = max(max(VisP2Data(:,:,6)));
                fprintf('~~~ From vision update table ~~~\n');
                fprintf('%d Board blocks detected. %d P1 blocks detected. %d P2 blocks detected\n', ...
                    app.BoardNum, app.P1Num, app.P2Num );
                
                % 3. BPs - see what blocks lie outside the occupancy grids and save -----
                ir = sort([irOG; irP1; irP2;]);
                outliers = ismember(blocks(:,6), ir);
                app.TableData = blocks(~outliers,1:5);
                
                % 3.5 Convert Vision OG to GUI OG
                app.BoardData = visionToGUI(VisBoardData);
                app.P1Data = visionToGUI(VisP1Data);
                app.P2Data = visionToGUI(VisP2Data);
                
%                 % 4. update plot angles and centroids ------------------------------------
%                 % Angle vectors
%                 [angleVecsX, angleVecsY] = pol2cart(deg2rad(blocks(:,3)), 5);
%                 angleVecsX = angleVecsX + blocks(:,1);
%                 angleVecsY = angleVecsY + blocks(:,2);
                
%                 for i = 1:size(blocks, 1)
%                     xy = [blocks(i,1:2); angleVecsX(i) angleVecsY(i);];
%                     set(app.TabVidAxes, 'xdata', xy(:,1), 'ydata',xy(:,2),'Color','blue');
%                 end
                
                % Centroids
%                 set(app.TabVidAxes, 'xdata', blocks(:,1), 'ydata', blocks(:,2));
%                 hold(app.TabVidAxes, 'off');
                
                % 5. update GUI
                % list the stuff
                % BoardBlockListLabel = 
                % P1BlockListLabel = 
                % P2BlockListLabel = 
                % outlierBlockListLabel = 
                
%             catch
%                 disp('Error in table video tick');
%             end
        end

        % Button pushed function: UpdateConveyorButton
        function UpdateConveyorButtonPushed(app, event)
           % Try disp conveyor video
            try
                % 1. Get the snapshot --------------------
                %imageCon = getsnapshot(app.vidConveyor);
                imageCon = imread('conIm6.jpg');

                set(app.ConVidHandle, 'CData', imageCon); %show it in axes
                hold(app.ConVidAxes, 'on');
                
                % 2. Process the image for boxes ---------
                % and save the BP positions --------------
                [lines, app.conGridX, app.conGridY] = detectConBox(imageCon);
                
%                 %rotate the grid lines
%                 the = lines(2).angle;
%                 rot = [cosd(the) -sind(the); sind(the) cosd(the)];
%                 conGridXt = [app.conGridX; zeros(size(app.conGridX));];
%                 conGridXt(2,:) = app.conGridY(1);
%                 conGridYt = [zeros(size(app.conGridY)); app.conGridY;];
%                 conGridYt(1,:) = app.conGridX(1);
                
                %rotX = rot*conGridXt;
                %rotY = rot*conGridYt;
                
                % 3. Process the image for blocks --------
                blocks = detectConBlock(imageCon);
                
                % 4. update the GUI
                set(app.ConVidAxes, 'xdata', blocks(:,1), 'ydata', blocks(:,2));
                hold(app.ConVidAxes, 'off');
                
            catch
                disp('Error in conveyor video tick');
            end
        end

        % Button pushed function: EditAddButton
        function EditAddButtonPushed(app, event)
            AddEditApp(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Assignment3AppUIFigure = uifigure;
            app.Assignment3AppUIFigure.Name = 'Assignment 3 App';
            app.Assignment3AppUIFigure.CloseRequestFcn = createCallbackFcn(app, @Assignment3AppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.Assignment3AppUIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.BoardBlockList = uitable(app.OverviewTab);
            app.BoardBlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.BoardBlockList.RowName = {};
            app.InsertBoxButton = uibutton(app.OverviewTab, 'push');
            app.InsertBoxButton.Text = 'Insert Box';
            app.ReloadBoxButton = uibutton(app.OverviewTab, 'push');
            app.ReloadBoxButton.Text = 'Reload Box';
            app.ConveyorLabel = uilabel(app.OverviewTab);
            app.ConveyorLabel.Text = 'Conveyor';
            app.BoardBlockListLabel = uilabel(app.OverviewTab);
            app.BoardBlockListLabel.Text = 'Board Block List';
            app.EditAddButton = uibutton(app.OverviewTab, 'push');
            app.EditAddButton.ButtonPushedFcn = createCallbackFcn(app, @EditAddButtonPushed, true);
            app.EditAddButton.Text = 'Edit/Add';
            app.UpdateTableButton = uibutton(app.OverviewTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.UpdateConveyorButton = uibutton(app.OverviewTab, 'push');
            app.UpdateConveyorButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConveyorButtonPushed, true);
            app.UpdateConveyorButton.Text = 'Update Conveyor';
            app.VisionUpdateLabel = uilabel(app.OverviewTab);
            app.VisionUpdateLabel.Text = 'Vision Update';
            app.P1BlockList = uitable(app.OverviewTab);
            app.P1BlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.P1BlockList.RowName = {};
            app.P2BlockList = uitable(app.OverviewTab);
            app.P2BlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.P2BlockList.RowName = {};
            app.P1BlockListLabel = uilabel(app.OverviewTab);
            app.P1BlockListLabel.Text = 'P1 Block List';
            app.P2BlockListLabel = uilabel(app.OverviewTab);
            app.P2BlockListLabel.Text = 'P2 Block List';
            app.TableBlockListLabel_2 = uilabel(app.OverviewTab);
            app.TableBlockListLabel_2.Text = 'Table Block List';
            app.TableBlockList = uitable(app.OverviewTab);
            app.TableBlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.TableBlockList.RowName = {};
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.TabVidAxes = uiaxes(app.Table);
            app.ConveyorTab = uitab(app.TabGroup);
            app.ConveyorTab.Title = 'Conveyor';
            app.ConVidAxes = uiaxes(app.ConveyorTab);
            app.SimpleMoveTab = uitab(app.TabGroup);
            app.SimpleMoveTab.Title = 'Simple Move';
            app.XEditFieldLabel = uilabel(app.SimpleMoveTab);
            app.XEditFieldLabel.Text = 'X';
            app.XTabH = uieditfield(app.SimpleMoveTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.SimpleMoveTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YTabH = uieditfield(app.SimpleMoveTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.SimpleMoveTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZTabH = uieditfield(app.SimpleMoveTab, 'numeric');
            app.TabHSend = uibutton(app.SimpleMoveTab, 'push');
            app.TabHSend.ButtonPushedFcn = createCallbackFcn(app, @TabHSendPushed, true);
            app.TabHSend.Text = 'Send';
            app.RelativeToTableHomeLabel = uilabel(app.SimpleMoveTab);
            app.RelativeToTableHomeLabel.Text = 'Relative To Table Home';
            app.ComplexMoveTab = uitab(app.TabGroup);
            app.ComplexMoveTab.Title = 'Complex Move';
            app.TicTacToeTab = uitab(app.TabGroup);
            app.TicTacToeTab.Title = 'Tic Tac Toe';
            app.PathPlanTab = uitab(app.TabGroup);
            app.PathPlanTab.Title = 'Path Plan';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.MainTickLampLabel = uilabel(app.DebugTab);
            app.MainTickLampLabel.Text = 'Main Tick';
            app.MainTickLamp = uilamp(app.DebugTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIV1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Assignment3AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Assignment3AppUIFigure)
        end
    end
end