classdef GUIV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Assignment3AppUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        OverviewTab                  matlab.ui.container.Tab
        BoardBlockList               matlab.ui.control.Table
        InsertBoxButton              matlab.ui.control.Button
        ReloadBoxButton              matlab.ui.control.Button
        ConveyorLabel                matlab.ui.control.Label
        BoardBlockListLabel          matlab.ui.control.Label
        EditAddButton                matlab.ui.control.Button
        UpdateTableButton            matlab.ui.control.Button
        UpdateConveyorButton         matlab.ui.control.Button
        VisionUpdateLabel            matlab.ui.control.Label
        P1BlockList                  matlab.ui.control.Table
        P2BlockList                  matlab.ui.control.Table
        P1BlockListLabel             matlab.ui.control.Label
        P2BlockListLabel             matlab.ui.control.Label
        TableBlockListLabel_2        matlab.ui.control.Label
        TableBlockList               matlab.ui.control.Table
        MovetoHomeButton             matlab.ui.control.Button
        Table                        matlab.ui.container.Tab
        TabVidAxes                   matlab.ui.control.UIAxes
        ConveyorTab                  matlab.ui.container.Tab
        ConVidAxes                   matlab.ui.control.UIAxes
        SimpleMoveTab                matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        FromButtonGroup              matlab.ui.container.ButtonGroup
        BPButton                     matlab.ui.control.ToggleButton
        ConveyorButton               matlab.ui.control.ToggleButton
        BPZoneDropDownLabel          matlab.ui.control.Label
        BPZoneDropDown               matlab.ui.control.DropDown
        BPXDropDownLabel             matlab.ui.control.Label
        BPXDropDown                  matlab.ui.control.DropDown
        BPYDropDownLabel             matlab.ui.control.Label
        BPYDropDown                  matlab.ui.control.DropDown
        Panel_2                      matlab.ui.container.Panel
        ToButtonGroup                matlab.ui.container.ButtonGroup
        BPButton_2                   matlab.ui.control.ToggleButton
        ConveyorButton_2             matlab.ui.control.ToggleButton
        BPXDropDown_2                matlab.ui.control.DropDown
        BPYDropDown_2                matlab.ui.control.DropDown
        BPZoneDropDown_2Label        matlab.ui.control.Label
        BPZoneDropDown_2             matlab.ui.control.DropDown
        Panel_3                      matlab.ui.container.Panel
        BPXDropDown_3                matlab.ui.control.DropDown
        BPYDropDown_3                matlab.ui.control.DropDown
        BPZoneDropDown_3Label        matlab.ui.control.Label
        BPZoneDropDown_3             matlab.ui.control.DropDown
        RotatetoAlignLabel           matlab.ui.control.Label
        RotateButton                 matlab.ui.control.Button
        MoveButton                   matlab.ui.control.Button
        ComplexMoveTab               matlab.ui.container.Tab
        BPZoneDropDown_4Label        matlab.ui.control.Label
        BPZoneDropDown_4             matlab.ui.control.DropDown
        FillDeckLabel                matlab.ui.control.Label
        SendButton                   matlab.ui.control.Button
        ClearDeckLabel               matlab.ui.control.Label
        SendButton_2                 matlab.ui.control.Button
        BPZoneDropDown_5Label        matlab.ui.control.Label
        clearDeckSelect              matlab.ui.control.DropDown
        SortDecksLabel               matlab.ui.control.Label
        SendButton_3                 matlab.ui.control.Button
        ClearTableLabel              matlab.ui.control.Label
        SendButton_4                 matlab.ui.control.Button
        FillTableTextAreaLabel       matlab.ui.control.Label
        FillTableTextArea            matlab.ui.control.TextArea
        SendButton_5                 matlab.ui.control.Button
        TicTacToeTab                 matlab.ui.container.Tab
        PlayTicTacToeButton          matlab.ui.control.Button
        PathPlanTab                  matlab.ui.container.Tab
        BPXDropDown_4Label           matlab.ui.control.Label
        BPXDropDown_4                matlab.ui.control.DropDown
        BPYDropDown_4Label           matlab.ui.control.Label
        BPYDropDown_4                matlab.ui.control.DropDown
        StartBPLabel                 matlab.ui.control.Label
        EndBPLabel                   matlab.ui.control.Label
        BPXDropDown_5Label           matlab.ui.control.Label
        BPXDropDown_5                matlab.ui.control.DropDown
        BPYDropDown_5Label           matlab.ui.control.Label
        BPYDropDown_5                matlab.ui.control.DropDown
        PathPlanButton               matlab.ui.control.Button
        ExecuteButton                matlab.ui.control.Button
        DebugTab                     matlab.ui.container.Tab
        MainTickLampLabel            matlab.ui.control.Label
        MainTickLamp                 matlab.ui.control.Lamp
        DebugTextTextAreaLabel       matlab.ui.control.Label
        DebugTextTextArea            matlab.ui.control.TextArea
        ConveyorBlocksTextAreaLabel  matlab.ui.control.Label
        ConveyorBlocksTextArea       matlab.ui.control.TextArea
        PlannedPathTextAreaLabel     matlab.ui.control.Label
        PlannedPathTextArea          matlab.ui.control.TextArea
        ClearQueueButton             matlab.ui.control.Button
    end


    properties (Access = private)
        
        % Timer used for tick callback fucntion
        tickTimer;
        tickPeriod = 1; % Period of tick timer
        ticking = 0;
        
        % Video objects and handles
        vidTable;               % videoinput handle
        vidConveyor;            % videoinput handle
        TabVidHandle;   % imshow handle
        ConVidHandle;   % imshow handle
        
        % the commandQueue and communications
        queue = [];
        robotBusy = 0;
        socket = 0;
        connectType = 2;
        % Error flags
        
        genErr = 0;
        commError = 0;
        initFlag = 0;

        
        % Toggle set in tick (debug only)
        tickToggle;
        tickToggle2;
        
        % Data arrays for player 1, 2, Board and table
        P1Data;
        P2Data;
        BoardData;
        TableData;
        P1Num;  % Current num blocks in P1 deck
        P2Num;  % "
        BoardNum; % Current num blocks on board
        TableNum;
        allBlocks = [];
        
        BoardBlockVisionList = [];
        P1BlockVisionList = [];
        P2BlockVisionList = [];
        
        % Display handles
        tabCentroidHandle;
        tabAngleHandle;
        conCentroidHandle;
        conGridHandle;
        
        % Conveyor
        conGridX = [];
        conGridY = [];
        lines = [];
        conBlocks = [];
        boxCentroid = [];
        
        % Quat
        quat = [0 0 -1 0];
        
        % Path plan
        PlannedPath = [];
        
        % Tictactoe app
        Tictactoeapp = [];
        TTTOpen = 0;
        
        % Simulate
        SimTab = 0;
        SimCon = 0;
    end

    methods (Access = private)
    
        function InitVideoFeeds(app)
          % Init the video feeds
             try %create the table camera feed
                % Prep video object for manual and start it
                hold(app.TabVidAxes, 'on');  
                app.vidTable = videoinput('winvideo', 1, 'MJPG_1600x1200');
                %app.vidTable.ReturnedColorSpace = 'rgb';
                triggerconfig(app.vidTable, 'manual');
                src1 = getselectedsource(app.vidTable);
                src1.ExposureMode = 'manual';
                src1.Exposure = -5;
                src1.Contrast = 35;%
                %src1.Saturation = 78;
                start(app.vidTable);
                % Prep axis handle
                video_resolution1 = app.vidTable.VideoResolution;
                nbands1 = app.vidTable.NumberOfBands;
                app.TabVidHandle = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.TabVidAxes);
                
             catch %if theres no camera, then use an image
                 disp('FROM VIDEOFEEDINIT: No tab camera - using static images');
                 hold(app.TabVidAxes, 'on');
                 app.TabVidHandle = imshow(zeros(1200,1600), 'parent', app.TabVidAxes);
                 app.SimTab = 1;
             end
            
            % create tab image handles for plots

            app.tabCentroidHandle = plot(app.TabVidAxes,0,0, 'g+');
            app.tabAngleHandle = quiver(app.TabVidAxes,0,0,0,0, 0.2, 'r');
            hold(app.TabVidAxes, 'off');     
           
            try
                 % Prep video object for manual and start it
                 app.vidConveyor = videoinput('winvideo', 2, 'MJPG_1600x1200');
                 % app.vidConveyor.ReturnedColorSpace = 'rgb';
                 triggerconfig(app.vidConveyor, 'manual');
                 src1 = getselectedsource(app.vidConveyor);
                 src1.ExposureMode = 'manual';
                 src1.Exposure = -5;
                 %src1.Contrast = 35;%
                 start(app.vidConveyor);
                 % Prep axis handle
                 video_resolution2 = app.vidConveyor.VideoResolution;
                 nbands2 = app.vidConveyor.NumberOfBands;
                 hold(app.ConVidAxes, 'on');
                 app.ConVidHandle = imshow(zeros([video_resolution2(2), video_resolution2(1), nbands2]), 'Parent', app.ConVidAxes);  
                            
            catch
                disp('FROM VIDEOFEEDINIT: No con camera - using static images');
                hold(app.ConVidAxes, 'on');  
                app.ConVidHandle = imshow(zeros(1200,1600), 'parent', app.ConVidAxes);
                app.SimCon = 1;
            end
            %conveyer plot handle
            app.conCentroidHandle = plot(app.ConVidAxes,0,0, 'g+');
            app.conGridHandle(1) = plot(app.ConVidAxes,0,0,'b');
            app.conGridHandle(2) = plot(app.ConVidAxes,0,0,'b');
            
            hold(app.ConVidAxes, 'off');
                    
        end
        
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        % Tick Callback function (MAIN LOOP)
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function MainTickCallback(app,~,~)
%             app.ticking = 1;
%             if (~app.initFlag) && (~app.commError)
%                 % Connection case for first loop of connection (icluding being disconnected)
%                 % Initialization:
%                 [app.socket, app.connectType] = startConnectionToRobot();
%                 
%                 %if no proper connection, raise error
%                 if app.connectType == 2 %error
%                     app.commError = 1;
%                     app.initFlag = 1;
%                     disp('FROM MAIN: Initial Connection Failed')
%                 else
%                     app.commError = 0;
%                     app.initFlag = 1;
%                 end
%                 
%             end
%             
%             % Subseuqent loops
%             %----------------------------------
%             if (app.initFlag) && ~app.commError
%                 % normal connection
%                 % send the message on the queue to the robot and recieve a reply
%                 [app.queue, out, app.commError] = sendToRobot(app.queue, app.socket, app.genErr, app.robotBusy);
% 
%                 if ~app.commError && ~isempty(out)
%                     %process the message if there isn't a socket error
%                     % if there is a socket error then hold off because there isnt actually a message
%                     app.robotBusy = processMessage(out);
%                 end 
%                 
%             elseif (app.initFlag) && app.commError
%                 % lost connection halfway through
%                 % ask if you wana reconnect
%                 
%                 disp('FROM MAIN: Connection Lost');
%                 % Popup a connection error, if reconnect then go back to the init loop
%                 app.commError = connectionPopUp();
%                 app.commError = 1 - app.commError; %just to invert the answer i.e. yes = 0;
%                 
%                 app.initFlag = 0;
%                 
%             elseif (app.initFlag == 0) && app.commError
%                 % if they answered no
%                 app.Assignment3AppUIFigureCloseRequest();
%                 disp('FROM MAIN: DYING')
%             end
                        
            % Update the GUI lists based on data array
            UpdateDataLists(app);
            
            % Update the image axes based on data array
            UpdateImagePlot(app);
            
            % Send data to TTT
            if (app.TTTOpen)
                Setbusy(app.Tictactoeapp, app.robotBusy);
            end
            

%             end of main tick - show debugging stuff

            disp(['In queue: ' app.queue]);
            disp('-----------------------------------------------------');
            
%             flagText = sprintf('ERROR FLAGS - Busy: %d, Comms: %d, Init: %d, Gen: %d \n', app.robotBusy, app.commError, app.initFlag, app.genErr);
%             outText = sprintf('Output Message: %s \n', out);
%             
%             app.DebugTextTextArea.Value = {flagText; outText};
            % Display the conveyor blocks on the debug page
            conBlockCells = {'Conveyor blocks'};
            for i=1:size(app.conBlocks,1)
                temp = {num2str(app.conBlocks(i,:))};
                conBlockCells = [conBlockCells; temp];
            end
            app.ConveyorBlocksTextArea.Value = conBlockCells;
            
            % Display the path planned on the debug page
            pathPlannedCells = {'Path Planned'};
            for i=1:size(app.PlannedPath,1)
                temp = {num2str(app.PlannedPath(i,:))};
                pathPlannedCells = [pathPlannedCells; temp];
            end
            app.PlannedPathTextArea.Value = pathPlannedCells;
            
            % Flash the tick lamp on the debug page
            if (app.tickToggle == 1)
                app.MainTickLamp.Color = 'white';
                app.tickToggle = 0;
            else
                app.MainTickLamp.Color = 'green';
                app.tickToggle = 1;
            end
            
            app.ticking = 0;
        end
        
        % Hard close app
        function AppClose(app)
            disp("~~~~~ APP CLOSING ~~~~~");
            CloseConnection(app);
            StopTimers(app);
            pause(0.5);
            StopVideos(app);
            pause(0.5);
            %CloseFigs(app);
            delete(app);
        end
        
        % Close socket connection
        function CloseConnection(app)
            try
                if app.socket ~= 0
                    closeConnection(app.socket);         
                end
                
            catch
                disp('Socket close failed');
            end
        end
        
        % Function for gracefully kill all timers. (Thanks to the god that is Image Analyst on mathworks forums)
        function StopTimers(app)
            try
            	%fprintf('Entering StopTimer...\n');
            	listOfTimers = timerfindall; % List all timers, just for info.
            	% Get handle to the one timer that we should have.
            	if isempty(listOfTimers)
            		% Exit if there is no timer to turn off.
            		%fprintf('There are no timers to turn off. Leaving StopTimer().\n');
            		return;
            	end
            	% Stop that timer.
            	stop(app.tickTimer);
                delete(app.tickTimer);
            	% Delete all timers from memory.
            	listOfTimers = timerfindall;
            	if ~isempty(listOfTimers)
                        stop(listOfTimers(:));
            		delete(listOfTimers(:));
            	end
            	%fprintf('Left StopTimer and turned off all timers.\n');
            catch ME
            	errorMessage = sprintf('Error in StopTimer().\nThe error reported by MATLAB is:\n\n%s', ME.message);
            	fprintf('%s\n', errorMessage);
            end
        end
        
        % Stop the video by reset
        function StopVideos(app)
            imaqreset;
            %stop(app.vidTable);
            %stop(app.vidConveyor);

        end
        
        % Close all figs
        function CloseFigs(app)
            
        end
        
        function InitDataArrays(app)
            app.P1Data = NaN(6,1,6);      % Player decks are 1x6x6
            app.P2Data = NaN(6,1,6);
            app.BoardData = NaN(9,9,6);    % Board deck is 9x9x6
            app.TableData = NaN(0,6);
            app.P1Num = 0;
            app.P2Num = 0;
            app.BoardNum = 0;
            app.TableNum = 0;
        end
        
        % Add or edit player data
        function PlayerDataAddEdit(app, Player, Data)
            if (Player == 1)
                if (Data.Edit == 1) % Editing
                    % Get the block pos based off the number
                    [BPY, BPX] = GetBlockPos(Data.Number, app.P1Data);
                    if (BPY == -1)
                        return;
                    end 
                    % Check it is occupied
                    if (app.P1Data(BPY, BPX, 1) ~= 1)
                        return;
                    else
                        % Fill the data
                        app.P1Data(BPY, BPX, 1) = 1;
                        app.P1Data(BPY, BPX, 2) = Data.X;
                        app.P1Data(BPY, BPX, 3) = Data.Y;
                        app.P1Data(BPY, BPX, 4) = Data.Theta;
                        app.P1Data(BPY, BPX, 5) = Data.Type;
                        app.P1Data(BPY, BPX, 6) = Data.Number;
                    end
                elseif (Data.Edit == 0)    % Adding
                    % Check if it is occupied
                    if (app.P1Data(Data.BPY, Data.BPX, 1) == 1)
                        return;
                    else
                        % Fill the data
                        app.P1Data(Data.BPY, Data.BPX, 1) = 1;
                        app.P1Data(Data.BPY, Data.BPX, 2) = Data.X;
                        app.P1Data(Data.BPY, Data.BPX, 3) = Data.Y;
                        app.P1Data(Data.BPY, Data.BPX, 4) = Data.Theta;
                        app.P1Data(Data.BPY, Data.BPX, 5) = Data.Type;
                        app.P1Data(Data.BPY, Data.BPX, 6) = app.P1Num + 1;
                        app.P1Num = app.P1Num + 1;  % Increment counter
                    end
                else % Deleting
                    [tempBPY, tempBPX] = GetBlockPos(Data.Number, app.P1Data);
                    if (tempBPY == -1)
                        return;
                    end
                    DeleteBP(app, 'P1', tempBPX, tempBPY);
                end
            elseif (Player == 2)
                if (Data.Edit == 1) % Editing
                    % Get the block pos based off the number
                    [BPY, BPX] = GetBlockPos(Data.Number, app.P2Data);
                    if (BPY == -1)
                        return;
                    end
                    % Check it is occupied
                    if (app.P2Data(BPY, BPX, 1) ~= 1)
                        return;
                    else
                        % Fill the data
                        app.P2Data(BPY, BPX, 1) = 1;
                        app.P2Data(BPY, BPX, 2) = Data.X;
                        app.P2Data(BPY, BPX, 3) = Data.Y;
                        app.P2Data(BPY, BPX, 4) = Data.Theta;
                        app.P2Data(BPY, BPX, 5) = Data.Type;
                        app.P2Data(BPY, BPX, 6) = Data.Number;
                    end
                elseif (Data.Edit == 0)    % Adding
                    % Check if it is occupied
                    if (app.P2Data(Data.BPY, Data.BPX,1) == 1)
                        return;
                    else
                        % Fill the data
                        app.P2Data(Data.BPY, Data.BPX, 1) = 1;
                        app.P2Data(Data.BPY, Data.BPX, 2) = Data.X;
                        app.P2Data(Data.BPY, Data.BPX, 3) = Data.Y;
                        app.P2Data(Data.BPY, Data.BPX, 4) = Data.Theta;
                        app.P2Data(Data.BPY, Data.BPX, 5) = Data.Type;
                        app.P2Data(Data.BPY, Data.BPX, 6) = app.P2Num + 1;
                        app.P2Num = app.P2Num + 1;  % Increment counter
                    end
                else % Deleting
                    [tempBPY, tempBPX] = GetBlockPos(Data.Number, app.P2Data);
                    if (tempBPY == -1)
                        return;
                    end
                    DeleteBP(app, 'P2', tempBPX, tempBPY);
                end
            end
        end
        
        function BoardDataAddEdit(app, Data)
            if (Data.Edit == 1) % Editing
                % Get the block pos based off the number
                [BPY, BPX] = GetBlockPos(Data.Number, app.BoardData);
                if (BPY == -1)
                    return;
                end 
                if (app.BoardData(BPY, BPX, 1) ~= 1)
                    return;
                else
                    % Fill the data
                    app.BoardData(BPY, BPX, 1) = 1;
                    app.BoardData(BPY, BPX, 2) = Data.X;
                    app.BoardData(BPY, BPX, 3) = Data.Y;
                    app.BoardData(BPY, BPX, 4) = Data.Theta;
                    app.BoardData(BPY, BPX, 5) = Data.Type;
                    app.BoardData(BPY, BPX, 6) = Data.Number;
                end
            elseif (Data.Edit == 0)    % Adding
                % Check if it is occupied
                if (app.BoardData(Data.BPY, Data.BPX,1) == 1)
                    return;
                else
                    % Fill the data
                    app.BoardData(Data.BPY, Data.BPX, 1) = 1;
                    app.BoardData(Data.BPY, Data.BPX, 2) = Data.X;
                    app.BoardData(Data.BPY, Data.BPX, 3) = Data.Y;
                    app.BoardData(Data.BPY, Data.BPX, 4) = Data.Theta;
                    app.BoardData(Data.BPY, Data.BPX, 5) = Data.Type;
                    app.BoardData(Data.BPY, Data.BPX, 6) = app.BoardNum + 1;
                    app.BoardNum = app.BoardNum + 1;  % Increment counter
                end
            else % Deleting
                [tempBPY, tempBPX] = GetBlockPos(Data.Number, app.BoardData);
                if (tempBPY == -1)
                    return;
                end
                DeleteBP(app, 'Board', tempBPX, tempBPY);
            end
        end
        
        function TableDataAddEdit(app, Data)
            if (Data.Edit == 1) % Editing
                % Get the block pos based off the number
                DataNum = GetTablePos(Data.Number, app.TableData);
                if (DataNum == -1)
                    return;
                end 
                if (app.TableData(:,1) ~= 1)
                    return;
                else
                    % Fill the data
                    app.TableData(DataNum, 1) = 1;
                    app.TableData(DataNum, 2) = Data.X;
                    app.TableData(DataNum, 3) = Data.Y;
                    app.TableData(DataNum, 4) = Data.Theta;
                    app.TableData(DataNum, 5) = Data.Type;
                    app.TableData(DataNum, 6) = Data.Number;
                end
            elseif (Data.Edit == 0)    % Adding
                % Fill the data
                temp = zeros(1,6);
                temp(1, 1) = 1;
                temp(1, 2) = Data.X;
                temp(1, 3) = Data.Y;
                temp(1, 4) = Data.Theta;
                temp(1, 5) = Data.Type;
                temp(1, 6) = app.TableNum + 1;
                app.TableData = [app.TableData; temp];
                app.TableNum = app.TableNum + 1;  % Increment counter
            else % Deleting
                [DataNum] = GetTablePos(Data.Number, app.TableData);
                if (DataNum == -1)
                    return;
                end
                DeleteBP(app, 'Table', DataNum);
            end
        end
        
        function UpdateDataLists(app)
            % P1
            PrepP1Data = PrepareData(app.P1Num, app.P1Data);
            app.P1BlockList.Data = PrepP1Data;
            
            % P2
            PrepP2Data = PrepareData(app.P2Num, app.P2Data);
            app.P2BlockList.Data = PrepP2Data;
            
            % Board
            PrepBoardData = PrepareData(app.BoardNum, app.BoardData);
            app.BoardBlockList.Data = PrepBoardData;
            
            % Table
            PrepTableData = PrepareDataTable(app.TableNum, app.TableData);
            app.TableBlockList.Data = PrepTableData;
            
        end
        
        function UpdateImagePlot(app)
            hold(app.TabVidAxes,'on');
            hold(app.ConVidAxes,'on');

            % TABLE -----------------------------------------------------------
            % P1
            PrepP1ImData = PrepareImageData(app.P1Num, app.P1Data);
            % P2
            PrepP2ImData = PrepareImageData(app.P2Num, app.P2Data);
            % Board
            PrepBoardImData = PrepareImageData(app.BoardNum, app.BoardData);
            % Off the table
            if numel(app.TableData ~= 0)
                PrepTabImData = app.TableData(:,2:4);
            else
                PrepTabImData = [];
            end

            totalData = [PrepP1ImData; PrepP2ImData; PrepBoardImData; PrepTabImData];
            set(app.tabCentroidHandle, 'xdata', totalData(:,1), 'ydata', totalData(:,2));
            
            % block angles
            u = [cos(totalData(:,3)),sin(totalData(:,3))];
            set(app.tabAngleHandle,'xdata', totalData(:,1),'ydata', totalData(:,2), 'udata', u(:,1), 'vdata', u(:,2));
            
            % CONVEYOR ----------------------------------------------------------------------
            % ADDED BY STEVEN - CENTROIDS
            if numel(app.conBlocks) ~= 0
                set(app.conCentroidHandle, 'xdata', app.conBlocks(:,1), 'ydata',app.conBlocks(:,2));
            end
            
            % ADDED BY STEVEN - plots the detected lines found on the con box
            if size(app.lines,2) == 2
                conGridXt = [app.conGridX; zeros(size(app.conGridX));];
                conGridXt(2,:) = app.conGridY(1);
                set(app.conGridHandle(1), 'xdata', conGridXt(1,:), 'ydata', conGridXt(2,:));
                    
                conGridYt = [zeros(size(app.conGridY)); app.conGridY;];
                conGridYt(1,:) = app.conGridX(1);
                set(app.conGridHandle(2), 'xdata', conGridYt(1,:), 'ydata', conGridYt(2,:));
            end
            
            app.allBlocks = totalData;

            hold(app.TabVidAxes,'off')
            hold(app.ConVidAxes,'off');


        end
        
        function MainTickErrorCallback(app,a,b)

        end
        
        function DeleteBP(app, Zone, BPX, BPY)
            if (strcmp(Zone, 'Board'))
                DelNum = app.BoardData(BPY, BPX, 6);
                app.BoardData(BPY, BPX, :) = NaN;
                for i=DelNum:app.BoardNum
                    [y, x] = GetBlockPos(i + 1,app.BoardData);
                    if (y == -1)
                        continue;
                    end
                    app.BoardData(y, x, 6) = i;
                end
                app.BoardNum = app.BoardNum - 1;
            elseif (strcmp(Zone, 'P1'))
                DelNum = app.P1Data(BPY, BPX, 6);
                app.P1Data(BPY, BPX, :) = NaN;
                for i=DelNum:app.P1Num
                    [y, x] = GetBlockPos(i + 1,app.P1Data);
                    if (y == -1)
                        continue;
                    end
                    app.P1Data(y, x, 6) = i;
                end
                app.P1Num = app.P1Num - 1;
            elseif (strcmp(Zone, 'P2'))
                DelNum = app.P2Data(BPY, BPX, 6);
                app.P2Data(BPY, BPX, :) = NaN;
                for i=DelNum:app.P2Num
                    [y, x] = GetBlockPos(i + 1,app.P2Data);
                    if (y == -1)
                        continue;
                    end
                    app.P2Data(y, x, 6) = i;
                end
                app.P2Num = app.P2Num - 1;
            elseif (strcmp(Zone, 'Table'))
                DelNum = BPX;
                for i=DelNum:app.TableNum
                    y = GetTablePos(i + 1, app.TableData);
                    if (y == -1)
                        continue;
                    end
                    app.TableData(y, 6) = i;
                end
                app.TableData(DelNum, :) = [];
                app.TableNum = app.TableNum - 1;
            else
                disp('Error: Invalid DeleteBP Zone');
            end
        end
        
        function DeleteAllBP(app, Zone)
            if (strcmp(Zone, 'Board'))
                app.BoardData(:, :, :) = NaN;
                app.BoardNum = 0;
            elseif (strcmp(Zone, 'P1'))
                app.P1Data(:, :, :) = NaN;
                app.P1Num = 0;
            elseif (strcmp(Zone, 'P2'))
                app.P2Data(:, :, :) = NaN;
                app.P2Num = 0;
            elseif (strcmp(Zone, 'Table'))
                app.TableData(:, :) = [];
                app.TableNum = 0;
            else
                disp('Error: Invalid DeleteBP Zone');
            end
        end


        function BPMovement(app, fX, fY, fZ, tX, tY, tZ)

                % Create from cmd
                fCoords = imgToGlob([fX,fY],1);
                fCoords(3) = fCoords(3) + fZ;
                fQuat = double([0 0 -1.0 0]);
                fData = createMovDataString(fCoords, fQuat);
                fCmd = createCmdString('MC',fData);
                
                 % Create to cmd
                tCoords = imgToGlob([tX,tY],1);
                tCoords(3) = tCoords(3) + tZ;
                tQuat = double([0 0 -1.0 0]);
                
                tData = createMovDataString(tCoords, tQuat);
                tCmd = createCmdString('MC',tData);
                
                % Create succ cmd
                sucOnCmd = createCmdString('SS', 'on');
                sucOffCmd = createCmdString('SE', 'off');
                
                % Send to queue
                app.queue = sendToQueue(app.queue, fCmd); % Send from command
                app.queue = sendToQueue(app.queue, sucOnCmd); % Send succ on command
                app.queue = sendToQueue(app.queue, tCmd); % Send from command
                app.queue = sendToQueue(app.queue, sucOffCmd); % Send succ off command
        
        end


    end

    methods (Access = public)
        % Public function to receive data from external input app
        function ReceiveAddEditData(app, Data)
            if (Data.BPZone == 1 || Data.BPZone == 2)
                PlayerDataAddEdit(app, Data.BPZone, Data)
            elseif (Data.BPZone == 3)
                BoardDataAddEdit(app, Data)
            elseif (Data.BPZone == 0)
                TableDataAddEdit(app, Data)
            else
                disp('Error: Invaild BP Zone returned from app');
            end
        end
        
% Public function to move a block in a BP to another BP
        % Inputs are main app, fBPZone ('P1', 'P2', 'Board'), fBPX (1, 2, 3) etc
        function MoveFromBPtoBP(app, fBPZone, fBPX, fBPY, tBPZone, tBPX, tBPY)
            
            % Check there is a block in the from pos
            fNum = 0;
            tNum = 0;
            switch fBPZone
                case 'P1'
                    fNum = 1;
                    if (app.P1Data(fBPY, fBPX, 1) ~= 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'No block in "from" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                case 'P2'
                    fNum = 2;
                    if (app.P2Data(fBPY, fBPX, 1) ~= 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'No block in "from" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                case 'Board'
                    fNum = 3;
                    if (app.BoardData(fBPY, fBPX, 1) ~= 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'No block in "from" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                otherwise
                    disp('Error: MoveFromBPtoBP fBPZone wrong');
            end
            % Check the to BP is free
            switch tBPZone
                case 'P1'
                    tNum = 1;
                    if (app.P1Data(tBPY, tBPX, 1) == 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'Block in "to" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                case 'P2'
                    tNum = 2;
                    if (app.P2Data(tBPY, tBPX, 1) == 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'Block in "to" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                case 'Board'
                    tNum = 3;
                    if (app.BoardData(tBPY, tBPX, 1) == 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'Block in "to" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                otherwise
                    disp('Error: MoveFromBPtoBP tBPZone wrong');
            end
            % Now we are ready to move the block
            % Move the block in memory (remember to change its X,Y (assume that the robot moves the centroid perfectally)
            % Add the movement to the queue

            if (fNum == 1 && tNum == 1) % P1 - P1
                % Move block data
                app.P1Data(tBPY, tBPX, :) = app.P1Data(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('P1', num2str(tBPX), num2str(tBPY));
                app.P1Data(tBPY, tBPX, 2) = tX;
                app.P1Data(tBPY, tBPX, 3) = tY;

                % Change num
                % Not req for P1 - P1

                % Prepare the data
                fX = app.P1Data(fBPY, fBPX, 2);
                fY = app.P1Data(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)

                % Remove old block data
                app.P1Data(fBPY, fBPX, :) = NaN;

            elseif (fNum == 1 && tNum == 2) % P1 - P2
                % Move block data
                app.P2Data(tBPY, tBPX, :) = app.P1Data(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('P2', num2str(tBPX), num2str(tBPY));
                app.P2Data(tBPY, tBPX, 2) = tX;
                app.P2Data(tBPY, tBPX, 3) = tY;

                % Change num
                app.P2Data(tBPY, tBPX, 6) = app.P2Num + 1;
                app.P2Num = app.P2Num + 1;

                % Prepare the data
                fX = app.P1Data(fBPY, fBPX, 2);
                fY = app.P1Data(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)                

                % Remove old block data
                DeleteBP(app, 'P1', fBPX, fBPY);

            elseif (fNum == 1 && tNum == 3) % P1 - Board
                % Move block data
                app.BoardData(tBPY, tBPX, :) = app.P1Data(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('main', num2str(tBPX), num2str(tBPY));
                app.BoardData(tBPY, tBPX, 2) = tX;
                app.BoardData(tBPY, tBPX, 3) = tY;

                % Change num
                app.BoardData(tBPY, tBPX, 6) = app.BoardNum + 1;
                app.BoardNum = app.BoardNum + 1;

                % Prepare the data
                fX = app.P1Data(fBPY, fBPX, 2);
                fY = app.P1Data(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)

                % Remove old block data
                DeleteBP(app, 'P1', fBPX, fBPY);

            elseif (fNum == 2 && tNum == 1) % P2 - P1
                % Move block data
                app.P1Data(tBPY, tBPX, :) = app.P2Data(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('P1', num2str(tBPX), num2str(tBPY));
                app.P1Data(tBPY, tBPX, 2) = tX;
                app.P1Data(tBPY, tBPX, 3) = tY;

                % Change num
                app.P1Data(tBPY, tBPX, 6) = app.P1Num + 1;
                app.P1Num = app.P1Num + 1;

                % Prepare the data
                fX = app.P2Data(fBPY, fBPX, 2);
                fY = app.P2Data(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)

                % Remove old block data
                DeleteBP(app, 'P2', fBPX, fBPY);

            elseif (fNum == 2 && tNum == 2) % P2 - P2
                % Move block data
                app.P2Data(tBPY, tBPX, :) = app.P2Data(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('P2', num2str(tBPX), num2str(tBPY));
                app.P2Data(tBPY, tBPX, 2) = tX;
                app.P2Data(tBPY, tBPX, 3) = tY;

                % Change num
                % Not req for P2 - P2

                % Prepare the data
                fX = app.P2Data(fBPY, fBPX, 2);
                fY = app.P2Data(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)                

                % Remove old block data
                app.P2Data(fBPY, fBPX, :) = NaN;

            elseif (fNum == 2 && tNum == 3) % P2 - Board
                % Move block data
                app.BoardData(tBPY, tBPX, :) = app.P2Data(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('main', num2str(tBPX), num2str(tBPY));
                app.BoardData(tBPY, tBPX, 2) = tX;
                app.BoardData(tBPY, tBPX, 3) = tY;

                % Change num
                app.BoardData(tBPY, tBPX, 6) = app.BoardNum + 1;
                app.BoardNum = app.BoardNum + 1;

                % Prepare the data
                fX = app.P2Data(fBPY, fBPX, 2);
                fY = app.P2Data(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)                

                % Remove old block data
                DeleteBP(app, 'P2', fBPX, fBPY);

            elseif (fNum == 3 && tNum == 1) % Board - P1
                % Move block data
                app.P1Data(tBPY, tBPX, :) = app.BoardData(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('P1', num2str(tBPX), num2str(tBPY));
                app.P1Data(tBPY, tBPX, 2) = tX;
                app.P1Data(tBPY, tBPX, 3) = tY;

                % Change num
                app.P1Data(tBPY, tBPX, 6) = app.P1Num + 1;
                app.P1Num = app.P1Num + 1;

                % Prepare the data
                fX = app.BoardData(fBPY, fBPX, 2);
                fY = app.BoardData(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)                

                % Remove old block data
                DeleteBP(app, 'Board', fBPX, fBPY);

            elseif (fNum == 3 && tNum == 2) % Board - P2
                % Move block data
                app.P2Data(tBPY, tBPX, :) = app.BoardData(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('P2', num2str(tBPX), num2str(tBPY));
                app.P2Data(tBPY, tBPX, 2) = tX;
                app.P2Data(tBPY, tBPX, 3) = tY;

                % Change num
                app.P2Data(tBPY, tBPX, 6) = app.P2Num + 1;
                app.P2Num = app.P2Num + 1;

                % Prepare the data
                fX = app.BoardData(fBPY, fBPX, 2);
                fY = app.BoardData(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)                 

                % Remove old block data
                DeleteBP(app, 'Board', fBPX, fBPY);

            elseif (fNum == 3 && tNum == 3) % Board - Board
                % Move block data
                app.BoardData(tBPY, tBPX, :) = app.BoardData(fBPY, fBPX, :);

                % Change x,y
                [tX,tY] = BPtoCoord('main', num2str(tBPX), num2str(tBPY));
                app.BoardData(tBPY, tBPX, 2) = tX;
                app.BoardData(tBPY, tBPX, 3) = tY;

                % Change num
                % Not req for Board - Board

                % Prepare the data
                fX = app.BoardData(fBPY, fBPX, 2);
                fY = app.BoardData(fBPY, fBPX, 3);
                fZ = 15;
                tZ = 15;

                % Add movement to the queue
                BPMovement(app, fX, fY, fZ, tX, tY, tZ)                 

                % Remove old block data
                app.BoardData(fBPY, fBPX, :) = NaN;
            else 
                disp('Error: MoveFromBPtoBP fNum and tNum combo wrong');
            end
        end
        
        function MoveFromBPtoConveyor(app, fBPZone, fBPX, fBPY)
            % Check there is a block in the from pos
%           function MoveFromBPtoConveyor(app, fBPZone, fBPX, fBPY)
            if isempty(app.boxCentroid)
               disp('FROM MoveFromBPtoCon: No box detected');
               return;
            end
            
            switch fBPZone
                case 'P1'
                    if (app.P1Data(fBPY, fBPX, 1) ~= 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'No block in "from" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                    
                    fX = app.P1Data(fBPY, fBPX, 2);
                    fY = app.P1Data(fBPY, fBPX, 3);
                    fZ = 15;
                    DeleteBP(app, 'P1', fBPX, fBPY);
                    
                case 'P2'
                    if (app.P2Data(fBPY, fBPX, 1) ~= 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'No block in "from" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                    
                    fX = app.P2Data(fBPY, fBPX, 2);
                    fY = app.P2Data(fBPY, fBPX, 3);
                    fZ = 15;
                    DeleteBP(app, 'P2', fBPX, fBPY);
                    
                case 'Board'
                    if (app.BoardData(fBPY, fBPX, 1) ~= 1)
                        uiconfirm(app.Assignment3AppUIFigure, 'No block in "from" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                    
                    fX = app.BoardData(fBPY, fBPX, 2);
                    fY = app.BoardData(fBPY, fBPX, 3);
                    fZ = 15;
                    DeleteBP(app, 'Board', fBPX, fBPY);
                    
                otherwise
                    disp('Error: MoveFromBPtoBP fBPZone wrong');
            end
            
            % Now we are ready to move the block ----------------------------------------------
            % Delete the block in memory (use the DeleteBP function (should handle everything))
            coords = imgToGlob([fX fY], 1);
            coords(3) = coords(3) + fZ;
            tQuat = double([0 0 -1.0 0]);
            
            data = createMovDataString(coords, tQuat);
            initCmd = createCmdString('MC', data);
            
            % get a target move command ------------------------------
            %---------------------------------------------------------
            coords = imgToGlob(app.boxCentroid, 0);
            coords(3) = coords(3) + 60;
            tQuat = double([0 0 -1.0 0]);
            
            data = createMovDataString(coords, tQuat);
            destCmd = createCmdString('MC', data);
            
            % create suction comnands ---------------------------------
            % ---------------------------------------------------------
            sucOnCmd = createCmdString('SS', 'on');
            sucOffCmd = createCmdString('SE', 'off');
            
            % PUT IT OUT TO QUEUE ------------------------------------
            % --------------------------------------------------------
            app.queue = sendToQueue(app.queue, initCmd);
            app.queue = sendToQueue(app.queue, sucOnCmd);
            app.queue = sendToQueue(app.queue, destCmd);
            app.queue = sendToQueue(app.queue, sucOffCmd);
            
        end
        
        function MoveFromConveyortoBP(app, tBPZone, tBPX, tBPY)
            % If there a no blocks on the conveyor
            if (numel(app.conBlocks) == 0)
                uiconfirm(app.Assignment3AppUIFigure, 'No blocks on conveyor','Conveyor Error','Options',{'OK'});
                return;
            end
            switch tBPZone
               % Check the to BP is free
                case 'P1'
                    if (app.P1Data(tBPY, tBPX, 1) == 1)
                    % if the destination is occupied
                        uiconfirm(app.Assignment3AppUIFigure, 'Block in "to" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                    
                    %otherwise populate 
                    app.P1Data(tBPY, tBPX, 1) = 1;%occ
                    [destX, destY] = BPtoCoord('P1', num2str(tBPX), num2str(tBPY)); %x y
                    app.P1Data(tBPY, tBPX, 2:3) = [destX, destY];
                    app.P1Data(tBPY, tBPX, 4) = 0; %theta
                    app.P1Data(tBPY, tBPX, 5) = app.conBlocks(1,4);
                    app.P1Data(tBPY, tBPX, 6) = app.P1Num + 1;
                    app.P1Num = app.P1Num + 1;
                    
                case 'P2'
                    if (app.P2Data(tBPY, tBPX, 1) == 1)
                        % if the destination is occupied
                        uiconfirm(app.Assignment3AppUIFigure, 'Block in "to" BP position','BP Error','Options',{'OK'});
                        return;
                    end

                    %otherwise populate 
                    app.P2Data(tBPY, tBPX, 1) = 1;%occ
                    [destX, destY] = BPtoCoord('P2', num2str(tBPX), num2str(tBPY));
                    app.P2Data(tBPY, tBPX, 2:3) = [destX, destY]; %x y
                    app.P2Data(tBPY, tBPX, 4) = 0; %theta
                    app.P2Data(tBPY, tBPX, 5) = app.conBlocks(1,4);
                    app.P2Data(tBPY, tBPX, 6) = app.P2Num + 1;
                    app.P2Num = app.P2Num + 1;
                    
                case 'Board'
                    if (app.BoardData(tBPY, tBPX, 1) == 1)
                        % if the destination is occupied
                        uiconfirm(app.Assignment3AppUIFigure, 'Block in "to" BP position','BP Error','Options',{'OK'});
                        return;
                    end
                    
                    %otherwise populate 
                    app.BoardData(tBPY, tBPX, 1) = 1;%occ
                    [destX, destY] = BPtoCoord('main', num2str(tBPX), num2str(tBPY)); %x y
                    app.BoardData(tBPY, tBPX, 2:3) = [destX, destY];
                    app.BoardData(tBPY, tBPX, 4) = 0; %theta
                    app.BoardData(tBPY, tBPX, 5) = app.conBlocks(1,4);
                    app.BoardData(tBPY, tBPX, 6) = app.BoardNum + 1;
                    app.BoardNum = app.BoardNum + 1;
                    
                otherwise
                    disp('Error: MoveFromBPtoBP tBPZone wrong');
            end
            
            if numel(app.conBlocks) ~= 0
                % get initial con block details and make a movement command -
                %------------------------------------------------------------
                bX = app.conBlocks(1,1);
                bY = app.conBlocks(1,2);
                bZ = 15;
                
                coords = imgToGlob([bX bY], 0);
                coords(3) = coords(3) + bZ;
                tQuat = double([0 0 -1.0 0]);
                
                data = createMovDataString(coords, tQuat);
                initCmd = createCmdString('MC', data);

                
                % get a target move command ------------------------------
                %---------------------------------------------------------
                coords = imgToGlob([destX destY], 1);
                coords(3) = coords(3) + bZ;
                tQuat = double([0 0 -1.0 0]);
                
                data = createMovDataString(coords, tQuat);
                destCmd = createCmdString('MC', data);
                
                % create suction comnands ---------------------------------
                sucOnCmd = createCmdString('SS', 'on');
                sucOffCmd = createCmdString('SE', 'off');
                
                % PUT IT OUT TO QUEUE ------------------------------------
                % --------------------------------------------------------
                app.queue = sendToQueue(app.queue, initCmd);
                app.queue = sendToQueue(app.queue, sucOnCmd);
                app.queue = sendToQueue(app.queue, destCmd);
                app.queue = sendToQueue(app.queue, sucOffCmd);
                
                app.conBlocks(1,:) = []; %delete con entry
                
            else
                disp('No Con Blocks to Select');
            end
                        
        end
        
        function [BPX, BPY] = NextAvailBlockTTT(app,boardType)
            switch boardType
                case 'P1'
                    boardOG = app.P1Data(:,:,1);
                case 'P2'
                    boardOG = app.P2Data(:,:,1);
                case 'main'
                    boardOG = app.BoardData(:,:,1);
            end
            [BPX, BPY] = nextAvailBlock(boardType, boardOG);s
        end
        
       function tabXYZtoCon(app, x ,y)
            if isempty(app.boxCentroid)
               disp('FROM XYZtoCon: No box detected');
               return;
            end
            
            z = 15;
            
            coords = imgToGlob([x y], 1);
            coords(3) = coords(3) + z;
            tQuat = double([0 0 -1.0 0]);
            
            data = createMovDataString(coords, tQuat);
            initCmd = createCmdString('MC', data);
            
            % get a target move command ------------------------------
            %---------------------------------------------------------
            coords = imgToGlob(app.boxCentroid, 0);
            coords(3) = coords(3) + 60;
            tQuat = double([0 0 -1.0 0]);
                    
            data = createMovDataString(coords, tQuat);
            destCmd = createCmdString('MC', data);
            
            % create suction comnands ---------------------------------
            % ---------------------------------------------------------
            sucOnCmd = createCmdString('SS', 'on');
            sucOffCmd = createCmdString('SE', 'off');
        
            % PUT IT OUT TO QUEUE ------------------------------------
            % --------------------------------------------------------
            app.queue = sendToQueue(app.queue, initCmd);
            app.queue = sendToQueue(app.queue, sucOnCmd);
            app.queue = sendToQueue(app.queue, destCmd);
            app.queue = sendToQueue(app.queue, sucOffCmd);
        end
        
        function TraceBPtoBP(app, tBPX, tBPY)
            % Get the xy data for that BP
            [tX,tY] = BPtoCoord('main', num2str(tBPX), num2str(tBPY));
            tZ = 5;

            % Add movement to the queue            
            % Create to cmd
            tCoords = imgToGlob([tX,tY],1);
            tCoords(3) = tCoords(3) + tZ;
            tQuat = double([0 0 -1.0 0]);
            tData = createMovDataString(tCoords, tQuat);
            tCmd = createCmdString('SM',tData);
            
            % Send to queue
            app.queue = sendToQueue(app.queue, tCmd); % Send from command
        
        end
        
        % Tic tac toe pause
        function TTTPause(app)
            pCmd = createCmdString('PA','Pause');
            query(app.socket, pCmd);
        end
        
        % Tic tac toe resume
        function TTTResume(app)
            rCmd = createCmdString('RE','Resume');
            query(app.socket, rCmd);
        end
        
        % Tic tac toe end game
        function TTTEndGame(app)
            % Sort deck
            event = [];
            SendButton_3Pushed(app, event);
        end
        
        % Tic tac toe closing
        function TTTClose(app)
            app.TTTOpen = 0;
            app.Tictactoeapp = [];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imaqreset;
            pause(0.5);
            %Flag initialization
            app.genErr = 0;
            app.commError = 0;
            app.initFlag = 0;
            
            % Start the video feeds and prep handles
            fprintf('\n\n\nINITIALIZING ... \n');
                        
            %try
                InitVideoFeeds(app);
            %catch
                %disp('Error in init video feed');
            %send           
            
            % Init the timer for the tick callback function
            app.tickTimer = timer('Period', app.tickPeriod,'ExecutionMode', 'fixedRate', ...
                                  'TasksToExecute', Inf,'BusyMode','drop','StartDelay', 2);
             
            app.tickTimer.TimerFcn = @(obj,event)app.MainTickCallback(app);
            %app.tickTimer.StopFcn = @(obj,event)app.stopTickCallback(app);
            app.tickTimer.ErrorFcn = @(obj,event)app.MainTickErrorCallback(app);
            
            start(app.tickTimer);            
            
            % Init the tick toggle
            app.tickToggle = 0;
            
            % Init the player and board data arrays
            InitDataArrays(app);
            
            % Init the drop downs in simple move
            event = [];
            BPZoneDropDownValueChanged(app, event);
            BPZoneDropDown_2ValueChanged(app, event);
            BPZoneDropDown_3ValueChanged(app, event);

        end

        % Close request function: Assignment3AppUIFigure
        function Assignment3AppUIFigureCloseRequest(app, event)
            AppClose(app);
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            %
                % 1. Get the snapshot --------------------
                if (app.SimTab == 1)
                    imageTab = imread('tabIm7.jpg');
                else
                    imageTab = getsnapshot(app.vidTable);
                end
                % Show it in the correct axes
                set(app.TabVidHandle, 'CData', imageTab); 
            
                
                % 2. Process the image for blocks ---------------------------
                blocks = detectBlock(imageTab);
                
                % 3. BPs - populate the occupancy grid and decks based on block centroids ----
                [app.BoardBlockVisionList, VisBoardData, irOG] = centroidToBoardBP(blocks);
                [app.P1BlockVisionList, VisP1Data, irP1] = centroidToP1Deck(blocks);
                [app.P2BlockVisionList, VisP2Data, irP2] = centroidToP2Deck(blocks);

                % 3. BPs - see what blocks lie outside the occupancy grids and save -----
                ir = sort([irOG; irP1; irP2;]);
                outliers = ismember(blocks(:,6), ir);
                VisTableData = blocks(~outliers,1:6);
                
                % 3.5 Convert Vision OG to GUI OG
                app.BoardData = visionToGUI(VisBoardData);
                app.P1Data = visionToGUI(VisP1Data);
                app.P2Data = visionToGUI(VisP2Data);
                app.TableData = visionToGUITable(VisTableData);
                
                % 3.6 Set the block count correctly.
                if any(~isnan(app.BoardData(:,1)))
                    app.BoardNum = max(max(app.BoardData(:,:,6)));
                else
                    app.BoardNum = 0;
                end
                if any(~isnan(app.P1Data(:,1)))
                    app.P1Num = max(max(app.P1Data(:,:,6)));
                else
                    app.P1Num = 0;
                end
                if any(~isnan(app.P2Data(:,1)))
                    app.P2Num = max(max(app.P2Data(:,:,6)));
                else
                    app.P2Num = 0;
                end
                if numel(app.TableData ~= 0)
                    app.TableNum = size(app.TableData(:,:),1);
                else
                    app.TableNum = 0;
                end
                
                % Debug prints
                fprintf('\n~~~ From vision update table ~~~\n');
                fprintf('%d Board blocks detected. %d P1 blocks detected. %d P2 blocks detected. %d Boardless\n', ...
                    app.BoardNum, app.P1Num, app.P2Num, app.TableNum);
                
        end

        % Button pushed function: UpdateConveyorButton
        function UpdateConveyorButtonPushed(app, event)
           % Try disp conveyor video
            try
                % 1. Get the snapshot --------------------
                if (app.SimCon == 1)
                    imageCon = imread('conIm6.jpg');
                else
                    imageCon = getsnapshot(app.vidConveyor);
                end
                set(app.ConVidHandle, 'CData', imageCon); %show it in axes                
                % 2. Process the image for boxes ---------
                % and save the BP positions --------------
                [app.lines, app.conGridX, app.conGridY, app.boxCentroid] = detectConBox(imageCon);
                
            %                 %rotate the grid lines
            %                 the = lines(2).angle;
            %                 rot = [cosd(the) -sind(the); sind(the) cosd(the)];
            %                 conGridXt = [app.conGridX; zeros(size(app.conGridX));];
            %                 conGridXt(2,:) = app.conGridY(1);
            %                 conGridYt = [zeros(size(app.conGridY)); app.conGridY;];
            %                 conGridYt(1,:) = app.conGridX(1);
                
                %rotX = rot*conGridXt;
                %rotY = rot*conGridYt;
                
                % 3. Process the image for blocks --------
                conVisBlocks = detectConBlock(imageCon);
                app.conBlocks = conVisBlocks;
                if numel(app.conBlocks ~= 0)
                    app.conBlocks(:,1) = round(app.conBlocks(:,1));
                    app.conBlocks(:,2) = round(app.conBlocks(:,2));
                end
            catch
                disp('Error in conveyor video tick');
            end
        end

        % Button pushed function: EditAddButton
        function EditAddButtonPushed(app, event)
            AddEditApp(app);
        end

        % Value changed function: BPZoneDropDown
        function BPZoneDropDownValueChanged(app, event)
            value = app.BPZoneDropDown.Value;
            if (strcmp(value, 'P1'))
                app.BPXDropDown.Items = {'1'};
                app.BPYDropDown.Items = {'1','2','3','4','5','6'};
            elseif (strcmp(value, 'Board'))
                app.BPXDropDown.Items = {'1','2','3','4','5','6','7','8','9'};
                app.BPYDropDown.Items = {'1','2','3','4','5','6','7','8','9'};
            elseif (strcmp(value, 'P2'))
                app.BPXDropDown.Items = {'1'};
                app.BPYDropDown.Items = {'1','2','3','4','5','6'};
            end
        end

        % Selection changed function: FromButtonGroup
        function FromButtonGroupSelectionChanged(app, event)
            selectedButton = app.FromButtonGroup.SelectedObject;
            if (strcmp(selectedButton.Text, 'BP'))
                app.BPZoneDropDown.Enable = 'on';
                app.BPXDropDown.Enable = 'on';
                app.BPYDropDown.Enable = 'on';
            elseif (strcmp(selectedButton.Text, 'Conveyor'))
                app.BPZoneDropDown.Enable = 'off';
                app.BPXDropDown.Enable = 'off';
                app.BPYDropDown.Enable = 'off';
            end
        end

        % Selection changed function: ToButtonGroup
        function ToButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToButtonGroup.SelectedObject;
            if (strcmp(selectedButton.Text, 'BP'))
                app.BPZoneDropDown_2.Enable = 'on';
                app.BPXDropDown_2.Enable = 'on';
                app.BPYDropDown_2.Enable = 'on';
            elseif (strcmp(selectedButton.Text, 'Conveyor'))
                app.BPZoneDropDown_2.Enable = 'off';
                app.BPXDropDown_2.Enable = 'off';
                app.BPYDropDown_2.Enable = 'off';
            end
        end

        % Value changed function: BPZoneDropDown_2
        function BPZoneDropDown_2ValueChanged(app, event)
            value = app.BPZoneDropDown_2.Value;
            if (strcmp(value, 'P1'))
                app.BPXDropDown_2.Items = {'1'};
                app.BPYDropDown_2.Items = {'1','2','3','4','5','6'};
            elseif (strcmp(value, 'Board'))
                app.BPXDropDown_2.Items = {'1','2','3','4','5','6','7','8','9'};
                app.BPYDropDown_2.Items = {'1','2','3','4','5','6','7','8','9'};
            elseif (strcmp(value, 'P2'))
                app.BPXDropDown_2.Items = {'1'};
                app.BPYDropDown_2.Items = {'1','2','3','4','5','6'};
            end
        end

        % Value changed function: BPZoneDropDown_3
        function BPZoneDropDown_3ValueChanged(app, event)
            value = app.BPZoneDropDown_3.Value;
            if (strcmp(value, 'P1'))
                app.BPXDropDown_3.Items = {'1'};
                app.BPYDropDown_3.Items = {'1','2','3','4','5','6'};
            elseif (strcmp(value, 'Board'))
                app.BPXDropDown_3.Items = {'1','2','3','4','5','6','7','8','9'};
                app.BPYDropDown_3.Items = {'1','2','3','4','5','6','7','8','9'};
            elseif (strcmp(value, 'P2'))
                app.BPXDropDown_3.Items = {'1'};
                app.BPYDropDown_3.Items = {'1','2','3','4','5','6'};
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            % Read the data in
            fromStr = app.FromButtonGroup.SelectedObject.Text; % Either 'BP' or 'Conveyor'
            toStr = app.ToButtonGroup.SelectedObject.Text; % Either 'BP' or 'Conveyor'
            
            fromBPZone = app.BPZoneDropDown.Value;
            fromBPX = str2double(app.BPXDropDown.Value);
            fromBPY = str2double(app.BPYDropDown.Value);
            toBPZone = app.BPZoneDropDown_2.Value;
            toBPX = str2double(app.BPXDropDown_2.Value);
            toBPY = str2double(app.BPYDropDown_2.Value);
            
            % BP to BP
            if (strcmp(fromStr,'BP') && strcmp(toStr,'BP'))
                MoveFromBPtoBP(app, fromBPZone, fromBPX, fromBPY, toBPZone, toBPX, toBPY);
            % BP to Conveyor
            elseif (strcmp(fromStr,'BP') && strcmp(toStr,'Conveyor'))
                MoveFromBPtoConveyor(app, fromBPZone, fromBPX, fromBPY);
            % Conveyor to BP
            elseif (strcmp(fromStr,'Conveyor') && strcmp(toStr,'BP'))
                MoveFromConveyortoBP(app, toBPZone, toBPX, toBPY);
            else
                disp('Error: You cant move from conveyor to conveyor you drongo');
                uiconfirm(app.Assignment3AppUIFigure, 'You cant move from conveyor to conveyor you drongo','User drongo','Options',{'I am a drongo'});
            end
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            
        end

        % Button pushed function: InsertBoxButton
        function InsertBoxButtonPushed(app, event)
            cmd = createCmdString('BI','10');
            app.queue = sendToQueue(app.queue, cmd);
        end

        % Button pushed function: ReloadBoxButton
        function ReloadBoxButtonPushed(app, event)
            cmd = createCmdString('BO','10');
            app.queue = sendToQueue(app.queue, cmd);
        end

        % Button pushed function: SendButton_5
        function SendButton_5Pushed(app, event)
            % FILL TABLE SEND
            % Read the text
            text = app.FillTableTextArea.Value;
            text = char(text);
            % Convert to array
            inputMatrix = str2num(text);
            % Check the input size is correct
            [y,x] = size(inputMatrix);
            if ((y ~= 9) && (x ~= 9))
                uiconfirm(app.Assignment3AppUIFigure, 'Input matrix is not 9x9','Input Matrix Error','Options',{'OK'});
                return;
            end
            % Get occupied positions on board
            currentOG = ~isnan(app.BoardData(:,:,1));
            % Find positions that are already occupied
            temp = inputMatrix & currentOG;
            % Produce final matrix of blocks to be placed
            finalOG = xor(inputMatrix, temp);
            % Find BPs to be placed
            [BPYlist, BPXlist] = find(finalOG == 1);
            if (isempty(BPYlist))
                uiconfirm(app.Assignment3AppUIFigure, 'Input block positions already all filled','Input Matrix Error','Options',{'OK'});
                return;
            end
            % Calc num blocks required
            numBlockReq = size(BPYlist,1);
            % Calc num blocks available in each deck
            numP1Block = sum(app.P1Data(:,:,1) == 1);
            numP2Block = sum(app.P2Data(:,:,1) == 1);
            numBlockTotal = numP1Block + numP2Block;
            % Compare how many we need and how many we got
            if (numBlockReq > numBlockTotal)
                uiconfirm(app.Assignment3AppUIFigure, 'Not enough blocks in the decks to fill table','Input Matrix Error','Options',{'OK'});
                return;
            end
            % Now we can move
            blockCount = 1;
            if (numBlockReq <= numP1Block)
                P1req = numBlockReq;
                P2req = 0;
            elseif (numBlockReq > numP1Block)
                P1req = numP1Block;
                P2req = numBlockReq - numP1Block;
            else
                disp('Error: Fill Table error math error???');
                return;
            end
            % Move blocks from P1
            for i=1:P1req
                [fBPX,fBPY] = nextAvailBlock('P1', app.P1Data(:,:,1));
                if (fBPX == -1)
                    disp('Error: Fill deck no nextAvailBlock in P1');
                    continue;
                end
                tBPX = BPXlist(blockCount,1);
                tBPY = BPYlist(blockCount,1);
                MoveFromBPtoBP(app, 'P1', fBPX, fBPY, 'Board', tBPX, tBPY);
                blockCount = blockCount + 1;
            end
            % Move blocks from P2
            for i=1:P2req
                [fBPX,fBPY] = nextAvailBlock('P2', app.P2Data(:,:,1));
                if (fBPX == -1)
                    disp('Error: Fill deck no nextAvailBlock in P2');
                    continue;
                end
                tBPX = BPXlist(blockCount,1);
                tBPY = BPYlist(blockCount,1);
                MoveFromBPtoBP(app, 'P2', fBPX, fBPY, 'Board', tBPX, tBPY);
                blockCount = blockCount + 1;
            end
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            % FILL DECK SEND
            toBPZone = app.BPZoneDropDown_4.Value;
            switch toBPZone
                
                case 'P1'
                    % Find how many con blocks and how many spots
                    numConBlocks = size(app.conBlocks,1);
                    numP1Spots = 6 - sum(app.P1Data(:,:,1) == 1);
                    if (numP1Spots <= 0)
                        uiconfirm(app.Assignment3AppUIFigure, 'No space in P1 deck to fill deck','Input Matrix Error','Options',{'OK'});
                        return;
                    end
                    % The blocks to move are the min of these two
                    numBlocksToMove = min(numConBlocks, numP1Spots);
                    for i=1:numBlocksToMove
                        [tBPX, tBPY] = nextAvailBP('P1',app.P1Data(:,:,1));
                        if (tBPX == -1)
                            disp('Error: Fill deck no nextAvailBP in P1');
                            continue;
                        end
                        MoveFromConveyortoBP(app, 'P1', tBPX, tBPY);
                    end
                case 'P2'
                    % Find how many con blocks and how many spots
                    numConBlocks = size(app.conBlocks,1);
                    numP2Spots = 6 - sum(app.P2Data(:,:,1) == 1);
                    if (numP2Spots <= 0)
                        uiconfirm(app.Assignment3AppUIFigure, 'No space in P2 deck to fill deck','Input Matrix Error','Options',{'OK'});
                        return;
                    end
                    % The blocks to move are the min of these two
                    numBlocksToMove = min(numConBlocks, numP2Spots);
                    for i=1:numBlocksToMove
                        [tBPX, tBPY] = nextAvailBP('P2',app.P2Data(:,:,1));
                        if (tBPX == -1)
                            disp('Error: Fill deck no nextAvailBP in P2');
                            continue;
                        end
                        MoveFromConveyortoBP(app, 'P2', tBPX, tBPY);
                    end
                otherwise
                    disp('Error: Fill Deck Send toBPZone wrong');
            end

        end

        % Button pushed function: SendButton_2
        function SendButton_2Pushed(app, event)
            % CLEAR DECK SEND\
            % function MoveFromBPtoConveyor(app, fBPZone, fBPX, fBPY)
            switch app.clearDeckSelect.Value
                
                case 'P1'
                    occupied = find(~isnan(app.P1Data(:,:,1)));
                    %disp(occupied);
                    for i = 1:size(occupied, 1)
                       MoveFromBPtoConveyor(app, 'P1', 1, occupied(i));
                    end
                    
                case 'P2'
                    occupied = find(~isnan(app.P2Data(:,:,1)));
                    %disp(occupied);
                    for i = 1:size(occupied, 1)
                       MoveFromBPtoConveyor(app, 'P2', 1, occupied(i));
                    end
                    
            end
            
        end

        % Button pushed function: SendButton_4
        function SendButton_4Pushed(app, event)
            % CLEAR TABLE SEND
            
            if isempty(app.boxCentroid)
               disp('FROM Send clear table: No box detected');
               return;
            end
            
            numOfBlocks = size(app.allBlocks,1);
                        
            for i = 1:numOfBlocks
                if isReachable([app.allBlocks(i,1), app.allBlocks(i,2)])
                    tabXYZtoCon(app, app.allBlocks(i,1), app.allBlocks(i,2));
                end
            end
            % Delete P1 Data
            DeleteAllBP(app, 'P1');
            % Delete P2 Data
            DeleteAllBP(app, 'P2');
            % Delete Board Data
            DeleteAllBP(app, 'Board');
            % Delete Table Data
            DeleteAllBP(app, 'Table');

        end

        % Button pushed function: SendButton_3
        function SendButton_3Pushed(app, event)
            % SORT DECKS SEND
                        % SORT DECKS SEND
            % MoveFromBPtoBP(app, fBPZone, fBPX, fBPY, tBPZone, tBPX, tBPY)
            % [BPX, BPY] = nextAvailBP(boardType, boardOG)

            %1 is P1 2 is P2 3 is board
            
            %get P1 out
            occupied = find(~isnan(app.P1Data(:,:,1))); %find occupied spaces
            for i = 1:app.P1Num
                [tBPX, tBPY] = nextAvailBP('main', app.BoardData(:,:,1));
                MoveFromBPtoBP(app, 'P1', 1, occupied(i), 'Board', tBPX, tBPY);
            end
            
            %get P2 out
            occupied = find(~isnan(app.P2Data(:,:,1))); %find occupied spaces
            for i = 1:app.P2Num
                [tBPX, tBPY] = nextAvailBP('main', app.BoardData(:,:,1));
                MoveFromBPtoBP(app, 'P2', 1, occupied(i),'Board', tBPX, tBPY);
            end
            
            %get blocks in P1
            [blocksX, blocksY] = find(app.BoardData(:,:,5) == 1); %find blocks
            
            if size(blocksX, 1) > 7
                blocksX = blocksX(1:6,:);
            end
            
            if size(blocksY, 1) > 7
                blocksY = blocksY(1:6,:);
            end
            
            for i = 1:size(blocksX)
                [tBPX, tBPY] = nextAvailBP('P1', app.P1Data(:,:,1));
                MoveFromBPtoBP(app, 'Board', blocksY(i), blocksX(i), 'P1', tBPX, tBPY);
            end
            
            %get letters in P2
            [blocksX, blocksY] = find(app.BoardData(:,:,5) == 0); %find blocks
            
            if size(blocksX, 1) > 7
                blocksX = blocksX(1:6,:);
            end
            
            if size(blocksY, 1) > 7
                blocksY = blocksY(1:6,:);
            end
            
            for i = 1:size(blocksX)
                [tBPX, tBPY] = nextAvailBP('P2', app.P2Data(:,:,1));
                MoveFromBPtoBP(app, 'Board', blocksY(i), blocksX(i), 'P2', tBPX, tBPY);
            end
        end

        % Button pushed function: PathPlanButton
        function PathPlanButtonPushed(app, event)
            % Read the data from the drop downs
            sBPX = str2double(app.BPXDropDown_4.Value);
            sBPY = str2double(app.BPYDropDown_4.Value);
            eBPX = str2double(app.BPXDropDown_5.Value);
            eBPY = str2double(app.BPYDropDown_5.Value);
            % Check the start and end positions are different
            if ((sBPX == eBPX) && (sBPY == eBPY))
                uiconfirm(app.Assignment3AppUIFigure, 'Start and end position are the same','Path Plan Input Error','Options',{'OK'});
                return;
            end
            % Check the start and end positions are not occupied
            if (app.BoardData(sBPY, sBPX, 1) == 1)
                uiconfirm(app.Assignment3AppUIFigure, 'Start position is already occupied','Path Plan Input Error','Options',{'OK'});
                return;
            end
            if (app.BoardData(eBPY, eBPX, 1) == 1)
                uiconfirm(app.Assignment3AppUIFigure, 'End position is already occupied','Path Plan Input Error','Options',{'OK'});
                return;
            end
            
            MapTemp = app.BoardData(:,:,1);
            nans = find(isnan(MapTemp));
            ones = find(MapTemp == 1);
            %[nany, nanx] = ind2sub(size(MapTemp), nans);
            MapTemp(nans) = 2;
            MapTemp(ones) = -1;
            Map = MapTemp;
            app.PlannedPath = pathplanning(Map,sBPX, sBPY, eBPX, eBPY);
        end

        % Button pushed function: PlayTicTacToeButton
        function PlayTicTacToeButtonPushed(app, event)
            % Check blocks are in correct position first
            P1blocks = sum(app.P1Data(:,:,1) == 1);
            P2blocks = sum(app.P2Data(:,:,1) == 1);
            BoardEmpty = sum(sum(app.BoardData(4:6,4:6,1) == 1));            
            if ((P1blocks ~= 6) || (P2blocks ~= 6) || (BoardEmpty ~= 0))
                uiconfirm(app.Assignment3AppUIFigure, '6 blocks are required in P1 & P2 deck and none in the centre 3x3 grid of the table','Path Plan Input Error','Options',{'OK'});
                return;
            end
            app.Tictactoeapp = Tictactoeapp(app);
            app.TTTOpen = 1;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            % Check that there is a path planned
            if (isempty(app.PlannedPath))
                uiconfirm(app.Assignment3AppUIFigure, 'No path planned','Path Plan Error','Options',{'OK'});
                return;
            end
            % Read the data from the drop downs
            sBPX = str2double(app.BPXDropDown_4.Value);
            sBPY = str2double(app.BPYDropDown_4.Value);
            eBPX = str2double(app.BPXDropDown_5.Value);
            eBPY = str2double(app.BPYDropDown_5.Value);
            
            % Move to the start position
            TraceBPtoBP(app, sBPX, sBPY);
            
            numBPMovs = size(app.PlannedPath,1);
            for i=1:numBPMovs
                nBPX = app.PlannedPath(i,1);
                nBPY = app.PlannedPath(i,2);
                TraceBPtoBP(app, nBPX, nBPY);
            end
        end

        % Button pushed function: MovetoHomeButton
        function MovetoHomeButtonPushed(app, event)
            hCmd = createCmdString('HP','Home');
            app.queue = sendToQueue(app.queue, hCmd);
        end

        % Button pushed function: ClearQueueButton
        function ClearQueueButtonPushed(app, event)
            ret = uiconfirm(app.Assignment3AppUIFigure, 'Are you sure you want to clear queue?','Clear Queue','Options',{'Yes' 'No'});
            switch ret
                case 'Yes'
                    app.queue = clearQueue(app.queue);
                case 'No'
                    return;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Assignment3AppUIFigure = uifigure;
            app.Assignment3AppUIFigure.Name = 'Assignment 3 App';
            app.Assignment3AppUIFigure.CloseRequestFcn = createCallbackFcn(app, @Assignment3AppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.Assignment3AppUIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.BoardBlockList = uitable(app.OverviewTab);
            app.BoardBlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.BoardBlockList.RowName = {};
            app.InsertBoxButton = uibutton(app.OverviewTab, 'push');
            app.InsertBoxButton.ButtonPushedFcn = createCallbackFcn(app, @InsertBoxButtonPushed, true);
            app.InsertBoxButton.Text = 'Insert Box';
            app.ReloadBoxButton = uibutton(app.OverviewTab, 'push');
            app.ReloadBoxButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadBoxButtonPushed, true);
            app.ReloadBoxButton.Text = 'Reload Box';
            app.ConveyorLabel = uilabel(app.OverviewTab);
            app.ConveyorLabel.Text = 'Conveyor';
            app.BoardBlockListLabel = uilabel(app.OverviewTab);
            app.BoardBlockListLabel.Text = 'Board Block List';
            app.EditAddButton = uibutton(app.OverviewTab, 'push');
            app.EditAddButton.ButtonPushedFcn = createCallbackFcn(app, @EditAddButtonPushed, true);
            app.EditAddButton.Text = 'Edit/Add';
            app.UpdateTableButton = uibutton(app.OverviewTab, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.UpdateConveyorButton = uibutton(app.OverviewTab, 'push');
            app.UpdateConveyorButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConveyorButtonPushed, true);
            app.UpdateConveyorButton.Text = 'Update Conveyor';
            app.VisionUpdateLabel = uilabel(app.OverviewTab);
            app.VisionUpdateLabel.Text = 'Vision Update';
            app.P1BlockList = uitable(app.OverviewTab);
            app.P1BlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.P1BlockList.RowName = {};
            app.P2BlockList = uitable(app.OverviewTab);
            app.P2BlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.P2BlockList.RowName = {};
            app.P1BlockListLabel = uilabel(app.OverviewTab);
            app.P1BlockListLabel.Text = 'P1 Block List';
            app.P2BlockListLabel = uilabel(app.OverviewTab);
            app.P2BlockListLabel.Text = 'P2 Block List';
            app.TableBlockListLabel_2 = uilabel(app.OverviewTab);
            app.TableBlockListLabel_2.Text = 'Table Block List';
            app.TableBlockList = uitable(app.OverviewTab);
            app.TableBlockList.ColumnName = {'Num'; 'X'; 'Y'; 'Theta'; 'Type'; 'BPX'; 'BPY'};
            app.TableBlockList.RowName = {};
            app.MovetoHomeButton = uibutton(app.OverviewTab, 'push');
            app.MovetoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoHomeButtonPushed, true);
            app.MovetoHomeButton.Text = 'Move to Home';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.TabVidAxes = uiaxes(app.Table);
            app.ConveyorTab = uitab(app.TabGroup);
            app.ConveyorTab.Title = 'Conveyor';
            app.ConVidAxes = uiaxes(app.ConveyorTab);
            app.SimpleMoveTab = uitab(app.TabGroup);
            app.SimpleMoveTab.Title = 'Simple Move';
            app.Panel = uipanel(app.SimpleMoveTab);
            app.FromButtonGroup = uibuttongroup(app.Panel);
            app.FromButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FromButtonGroupSelectionChanged, true);
            app.FromButtonGroup.Title = 'From';
            app.BPButton = uitogglebutton(app.FromButtonGroup);
            app.BPButton.Text = 'BP';
            app.BPButton.Value = true;
            app.ConveyorButton = uitogglebutton(app.FromButtonGroup);
            app.ConveyorButton.Text = 'Conveyor';
            app.BPZoneDropDownLabel = uilabel(app.Panel);
            app.BPZoneDropDownLabel.Text = 'BP Zone';
            app.BPZoneDropDown = uidropdown(app.Panel);
            app.BPZoneDropDown.Items = {'P1', 'Board', 'P2'};
            app.BPZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @BPZoneDropDownValueChanged, true);
            app.BPZoneDropDown.Value = 'P1';
            app.BPXDropDownLabel = uilabel(app.Panel);
            app.BPXDropDownLabel.Text = 'BPX';
            app.BPXDropDown = uidropdown(app.Panel);
            app.BPYDropDownLabel = uilabel(app.Panel);
            app.BPYDropDownLabel.Text = 'BPY';
            app.BPYDropDown = uidropdown(app.Panel);
            app.Panel_2 = uipanel(app.SimpleMoveTab);
            app.ToButtonGroup = uibuttongroup(app.Panel_2);
            app.ToButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToButtonGroupSelectionChanged, true);
            app.ToButtonGroup.Title = 'To';
            app.BPButton_2 = uitogglebutton(app.ToButtonGroup);
            app.BPButton_2.Text = 'BP';
            app.BPButton_2.Value = true;
            app.ConveyorButton_2 = uitogglebutton(app.ToButtonGroup);
            app.ConveyorButton_2.Text = 'Conveyor';
            app.BPXDropDown_2 = uidropdown(app.Panel_2);
            app.BPYDropDown_2 = uidropdown(app.Panel_2);
            app.BPZoneDropDown_2Label = uilabel(app.Panel_2);
            app.BPZoneDropDown_2Label.Text = 'BP Zone';
            app.BPZoneDropDown_2 = uidropdown(app.Panel_2);
            app.BPZoneDropDown_2.Items = {'P1', 'Board', 'P2'};
            app.BPZoneDropDown_2.ValueChangedFcn = createCallbackFcn(app, @BPZoneDropDown_2ValueChanged, true);
            app.BPZoneDropDown_2.Value = 'P1';
            app.Panel_3 = uipanel(app.SimpleMoveTab);
            app.BPXDropDown_3 = uidropdown(app.Panel_3);
            app.BPYDropDown_3 = uidropdown(app.Panel_3);
            app.BPZoneDropDown_3Label = uilabel(app.Panel_3);
            app.BPZoneDropDown_3Label.Text = 'BP Zone';
            app.BPZoneDropDown_3 = uidropdown(app.Panel_3);
            app.BPZoneDropDown_3.Items = {'P1', 'Board', 'P2'};
            app.BPZoneDropDown_3.ValueChangedFcn = createCallbackFcn(app, @BPZoneDropDown_3ValueChanged, true);
            app.BPZoneDropDown_3.Value = 'P1';
            app.RotatetoAlignLabel = uilabel(app.Panel_3);
            app.RotatetoAlignLabel.Text = 'Rotate to Align';
            app.RotateButton = uibutton(app.Panel_3, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.MoveButton = uibutton(app.SimpleMoveTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.ComplexMoveTab = uitab(app.TabGroup);
            app.ComplexMoveTab.Title = 'Complex Move';
            app.BPZoneDropDown_4Label = uilabel(app.ComplexMoveTab);
            app.BPZoneDropDown_4Label.Text = 'BP Zone';
            app.BPZoneDropDown_4 = uidropdown(app.ComplexMoveTab);
            app.BPZoneDropDown_4.Items = {'P1', 'P2'};
            app.BPZoneDropDown_4.Value = 'P1';
            app.FillDeckLabel = uilabel(app.ComplexMoveTab);
            app.FillDeckLabel.Text = 'Fill Deck';
            app.SendButton = uibutton(app.ComplexMoveTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ClearDeckLabel = uilabel(app.ComplexMoveTab);
            app.ClearDeckLabel.Text = 'Clear Deck';
            app.SendButton_2 = uibutton(app.ComplexMoveTab, 'push');
            app.SendButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendButton_2Pushed, true);
            app.SendButton_2.Text = 'Send';
            app.BPZoneDropDown_5Label = uilabel(app.ComplexMoveTab);
            app.BPZoneDropDown_5Label.Text = 'BP Zone';
            app.clearDeckSelect = uidropdown(app.ComplexMoveTab);
            app.clearDeckSelect.Items = {'P1', 'P2'};
            app.clearDeckSelect.Value = 'P1';
            app.SortDecksLabel = uilabel(app.ComplexMoveTab);
            app.SortDecksLabel.Text = 'Sort Decks';
            app.SendButton_3 = uibutton(app.ComplexMoveTab, 'push');
            app.SendButton_3.ButtonPushedFcn = createCallbackFcn(app, @SendButton_3Pushed, true);
            app.SendButton_3.Text = 'Send';
            app.ClearTableLabel = uilabel(app.ComplexMoveTab);
            app.ClearTableLabel.Text = 'Clear Table';
            app.SendButton_4 = uibutton(app.ComplexMoveTab, 'push');
            app.SendButton_4.ButtonPushedFcn = createCallbackFcn(app, @SendButton_4Pushed, true);
            app.SendButton_4.Text = 'Send';
            app.FillTableTextAreaLabel = uilabel(app.ComplexMoveTab);
            app.FillTableTextAreaLabel.Text = 'Fill Table';
            app.FillTableTextArea = uitextarea(app.ComplexMoveTab);
            app.FillTableTextArea.Value = {'0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'; '0, 0, 0, 0, 0, 0, 0, 0, 0'};
            app.SendButton_5 = uibutton(app.ComplexMoveTab, 'push');
            app.SendButton_5.ButtonPushedFcn = createCallbackFcn(app, @SendButton_5Pushed, true);
            app.SendButton_5.Text = 'Send';
            app.TicTacToeTab = uitab(app.TabGroup);
            app.TicTacToeTab.Title = 'Tic Tac Toe';
            app.PlayTicTacToeButton = uibutton(app.TicTacToeTab, 'push');
            app.PlayTicTacToeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTicTacToeButtonPushed, true);
            app.PlayTicTacToeButton.Text = 'Play Tic Tac Toe ';
            app.PathPlanTab = uitab(app.TabGroup);
            app.PathPlanTab.Title = 'Path Plan';
            app.BPXDropDown_4Label = uilabel(app.PathPlanTab);
            app.BPXDropDown_4Label.Text = 'BPX';
            app.BPXDropDown_4 = uidropdown(app.PathPlanTab);
            app.BPXDropDown_4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BPXDropDown_4.Value = '1';
            app.BPYDropDown_4Label = uilabel(app.PathPlanTab);
            app.BPYDropDown_4Label.Text = 'BPY';
            app.BPYDropDown_4 = uidropdown(app.PathPlanTab);
            app.BPYDropDown_4.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BPYDropDown_4.Value = '1';
            app.StartBPLabel = uilabel(app.PathPlanTab);
            app.StartBPLabel.Text = 'Start BP';
            app.EndBPLabel = uilabel(app.PathPlanTab);
            app.EndBPLabel.Text = 'End BP';
            app.BPXDropDown_5Label = uilabel(app.PathPlanTab);
            app.BPXDropDown_5Label.Text = 'BPX';
            app.BPXDropDown_5 = uidropdown(app.PathPlanTab);
            app.BPXDropDown_5.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BPXDropDown_5.Value = '1';
            app.BPYDropDown_5Label = uilabel(app.PathPlanTab);
            app.BPYDropDown_5Label.Text = 'BPY';
            app.BPYDropDown_5 = uidropdown(app.PathPlanTab);
            app.BPYDropDown_5.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BPYDropDown_5.Value = '1';
            app.PathPlanButton = uibutton(app.PathPlanTab, 'push');
            app.PathPlanButton.ButtonPushedFcn = createCallbackFcn(app, @PathPlanButtonPushed, true);
            app.PathPlanButton.Text = 'Path Plan';
            app.ExecuteButton = uibutton(app.PathPlanTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.MainTickLampLabel = uilabel(app.DebugTab);
            app.MainTickLampLabel.Text = 'Main Tick';
            app.MainTickLamp = uilamp(app.DebugTab);
            app.DebugTextTextAreaLabel = uilabel(app.DebugTab);
            app.DebugTextTextAreaLabel.Text = 'DebugText';
            app.DebugTextTextArea = uitextarea(app.DebugTab);
            app.DebugTextTextArea.Editable = 'off';
            app.ConveyorBlocksTextAreaLabel = uilabel(app.DebugTab);
            app.ConveyorBlocksTextAreaLabel.Text = 'Conveyor Blocks';
            app.ConveyorBlocksTextArea = uitextarea(app.DebugTab);
            app.ConveyorBlocksTextArea.Editable = 'off';
            app.PlannedPathTextAreaLabel = uilabel(app.DebugTab);
            app.PlannedPathTextAreaLabel.Text = 'Planned Path';
            app.PlannedPathTextArea = uitextarea(app.DebugTab);
            app.ClearQueueButton = uibutton(app.DebugTab, 'push');
            app.ClearQueueButton.ButtonPushedFcn = createCallbackFcn(app, @ClearQueueButtonPushed, true);
            app.ClearQueueButton.Text = 'Clear Queue';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUIV2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Assignment3AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Assignment3AppUIFigure)
        end
    end
end