classdef Tictactoeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        NewGamePanel      matlab.ui.container.Panel
        vsHumanButton     matlab.ui.control.Button
        vsAIButton        matlab.ui.control.Button
        GameOptionsPanel  matlab.ui.container.Panel
        PauseButton       matlab.ui.control.Button
        ResumeButton      matlab.ui.control.Button
        EndgameButton     matlab.ui.control.Button
        P1LampLabel       matlab.ui.control.Label
        P1Lamp            matlab.ui.control.Lamp
        P2LampLabel       matlab.ui.control.Label
        P2Lamp            matlab.ui.control.Lamp
        Button_10         matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
    end


    properties (Access = public)
        x = 2;
        o = 1;
        b = -1; % blank
        boardinit;% Description
        currentplayer;
        elem;
        mode;
        
        % Main App
        callingApp;
    end

    methods (Access = private)
    
        function Gameinit(app)
        app.currentplayer = randi(2);
        app.elem = ones(3,3)*app.b;
        app.boardinit = 0;
            if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            end
        end
        
        %% Minimax Algorithm implementation
function [bestchild, bestscore] = AImove(app,player,depth)
bestchild = [];

winflag = gamewinflag(app);
if (winflag || depth == 0)
    bestscore = finalev(app,player,depth);
else
    if (player)
        bestscore = -inf;
    else
        bestscore = inf;
    end
    childboard = makechildboard(app,player);
    temp = 1;
    reqsize = size(childboard,2)/3;
    while reqsize <=temp
        childboard1 = childboard(:,(3*temp - 2): 3*temp);
        presentscore = AImove(app,childboard1,~player, depth-1);
        if (player)
            if(bestscore<presentscore)
                bestscore = presentscore;
                bestchild = childboard1;
            end
        else
            if(bestscore>presentscore)
                bestscore = presentscore;
                bestchild = childboard1;
            end
        end
        temp = temp + 1;
    end
    
end
        
end

function bestscore = finalev(app,player,depth)
[winflag,winningplayer] = gamewinflag(app);
if winningplayer == app.b
    score = 0;
elseif winningplayer == app.x
    score = 10 + depth;
else
    score = -10-depth;
end
bestscore = score;

end

function childboard = makechildboard(app,player)
rowm = 3;
colm = 3;
rowc = 1;
childboard = [];
while rowc <= rowm
    colc = 1;
    while colc <= colm
        if (app.elem(rowc,colc) == app.b)
            dboard = app.elem;
            dboard(rowc,colc) = player;
            childboard = [childboard,dboard];
        end
        colc = colc+ 1;
    end
    rowc = rowc + 1;
end

   
end


function winflag = gamewinflag(app)
[winflag,bleh] = gamewin(app);
end
        
function [winflag, player] = gamewin(app)
% Player will be 1 or 2
%winflag will be set to various values
% 1 -win
% 0 - Still in play
% 2 - Draw
temp = app.elem(:);
for player = 1:2
    
if temp(1)==player && temp(4)==player && temp(7)==player
    winflag = 1; 
elseif temp(3)==player && temp(6)==player && temp(9)==player
    winflag = 1;
elseif temp(1)==player && temp(2)==player && temp(3)==player
    winflag = 1;
elseif temp(4)==player && temp(5)==player && temp(6)==player
    winflag = 1;
    elseif temp(2)==player && temp(5)==player && temp(8)==player
    winflag = 1;
elseif  temp(7)==player && temp(8)==player && temp(9)==player
    winflag = 1;
elseif temp(1)==player && temp(5)==player && temp(9)==player
    winflag = 1;
elseif temp(3)==player && temp(5)==player && temp(7)==player
    winflag = 1;
elseif isempty(min(find(temp==-1)))
    winflag = 2;
else
    winflag = 0;
end
end
end

function DisableforAI(app)
    
    app.Button_10.Enable = 'off';
    app.Button_2.Enable = 'off';
    app.Button_3.Enable = 'off';
    app.Button_4.Enable = 'off';
    app.Button_5.Enable = 'off';
    app.Button_6.Enable = 'off';
    app.Button_7.Enable = 'off';
    app.Button_8.Enable = 'off';
    app.Button_9.Enable = 'off';
end

function EnableafterAI(app)
    if app.elem(1,1) == -1
      app.Button_10.Enable = 'on'; 
    end
    if app.elem(1,2) == -1
      app.Button_2.Enable = 'on'; 
    end
    if app.elem(1,3) == -1
      app.Button_3.Enable = 'on'; 
    end
    if app.elem(2,1) == -1
      app.Button_4.Enable = 'on'; 
    end
    if app.elem(2,2) == -1
      app.Button_5.Enable = 'on';
    end
    if app.elem(2,3) == -1
      app.Button_6.Enable = 'on'; 
    end
    if app.elem(3,1) == -1
      app.Button_7.Enable = 'on'; 
    end
    if app.elem(3,2) == -1
      app.Button_8.Enable = 'on';
    end
    if app.elem(3,3) == -1
      app.Button_9.Enable = 'on'; 
    end
end
 
function UpdateAImove(app,changeinboard)
    for i = 1:3
        for j = 1:3
            if (app.elem(i,j) ~= changeinboard)
                movemade = [i,j];
            end
        end
    end
    
 if (movemade(1) ~= 1 && movemade(2) ~= 1)
     app.Button_10.Enable = 'off';
     app.Button_10.BackgroundColor = 'r';
     app.Button_10.Text = 'X';
     app.elem(1,1) = app.x;
     
 end
    
    
end

function GameOver(app)
        
    app.vsAIButton.Enable = 'on';
    app.vsHumanButton.Enable = 'on';
    app.PauseButton.Enable = 'off';
    app.ResumeButton.Enable = 'off';
    app.EndGameButton.Enable = 'off';
    
    app.Button_10.Enable = 'on';
    app.Button_10.BackgroundColor = 'g';
    app.Button_10.Text = '';
    app.Button_2.Enable = 'on';
    app.Button_2.BackgroundColor = 'g';
    app.Button_2.Text = '';
    app.Button_3.Enable = 'on';
    app.Button_3.BackgroundColor = 'g';
    app.Button_3.Text = '';
    app.Button_4.Enable = 'on';
    app.Button_4.BackgroundColor = 'g';
    app.Button_4.Text = '';
    app.Button_5.Enable = 'on';
    app.Button_5.BackgroundColor = 'g';
    app.Button_5.Text = '';
    app.Button_6.Enable = 'on';
    app.Button_6.BackgroundColor = 'g';
    app.Button_6.Text = '';
    app.Button_7.Enable = 'on';
    app.Button_7.BackgroundColor = 'g';
    app.Button_7.Text = '';
    app.Button_8.Enable = 'on';
    app.Button_8.BackgroundColor = 'g';
    app.Button_8.Text = '';
    app.Button_9.Enable = 'on';
    app.Button_9.BackgroundColor = 'g';
    app.Button_9.Text = '';
    
end


function SendMoveToMain(app)
    % 'P1' 'Board' 1 2 3 
    MoveFromBPtoBP(app.callingApp, fBPZone, fBPX, fBPY, tBPZone, tBPX, tBPY)
    % 'P1' 'P2'
    [fBPX,fBPY] = NextAvailBlockTTT(app,boardType);
end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
            
        end

        % Button pushed function: vsHumanButton
        function vsHumanButtonPushed(app, event)
            app.vsAIButton.Enable = 'off';
            app.vsHumanButton.Enable = 'off';
            app.mode = 1;
  %          app.PauseButton.Enable = 'on';
 %           app.ResumeButton.Enable = 'off';
%            app.EndGameButton.Enable = 'on';
            Gameinit(app);
            
            
        end

        % Button pushed function: vsAIButton
        function vsAIButtonPushed(app, event)
            app.vsAIButton.Enable = 'off';
            app.vsHumanButton.Enable = 'off';
           app.mode = 2;
           Gameinit(app);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
%% Basic stuff            
            app.Button_10.Enable = 'off';
            app.Button_10.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_10.Text = 'X';
            app.elem(1,1) = app.x;
            elseif app.currentplayer == app.o
            app.Button_10.Text = 'O';
            app.elem(1,1) = app.o;
            end
 %% Checkwin           
            [winflag, player] = gamewin(app);
            if winflag == 1
                if app.currentplayer == 1
                f = uifigure;
                uiconfirm(f,'Game Over','Player 2 Won','icon','info');
                elseif app.currentplayer == 2
                f = uifigure;
                uiconfirm(f,'Game Over','Player 1 Won','icon','info');
                end         
            elseif winflag == 2
                f = uifigure;
               uiconfirm(f,'Game Over','Tie Game','icon','info');
            end
 
%%computer move or multipayer
                %if (size(bestChild,1) >0)
	        %state = bestChild;
		%winner = gameStat(bestChild);
		%end
		
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end


            if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable = 'off';
            app.Button_2.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_2.Text = 'X';
            app.elem(1,2) = app.x;
            elseif app.currentplayer == app.o
            app.Button_2.Text = 'O';
            app.elem(1,2) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
             if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
             end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Enable = 'off';
            app.Button_3.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_3.Text = 'X';
            app.elem(1,3) = app.x;
            elseif app.currentplayer == app.o
            app.Button_3.Text = 'O';
            app.elem(1,3) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
             if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
             end   
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.Enable = 'off';
            app.Button_4.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_4.Text = 'X';
            app.elem(2,1) = app.x;
            elseif app.currentplayer == app.o
            app.Button_4.Text = 'O';
            app.elem(2,1) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
            if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Button_5.Enable = 'off';
            app.Button_5.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_5.Text = 'X';
            app.elem(2,2) = app.x;
            elseif app.currentplayer == app.o
            app.Button_5.Text = 'O';
            app.elem(2,2) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
            if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
             end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Button_6.Enable = 'off';
            app.Button_6.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_6.Text = 'X';
            app.elem(2,3) = app.x;
            elseif app.currentplayer == app.o
            app.Button_6.Text = 'O';
            app.elem(2,3) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
             if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
             end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Button_7.Enable = 'off';
            app.Button_7.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_7.Text = 'X';
            app.elem(3,1) = app.x;
            elseif app.currentplayer == app.o
            app.Button_7.Text = 'O';
            app.elem(3,1) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
             if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
             end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Button_8.Enable = 'off';
            app.Button_8.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_8.Text = 'X';
            app.elem(3,2) = app.x;
            elseif app.currentplayer == app.o
            app.Button_8.Text = 'O';
            app.elem(3,2) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
             if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
             end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Button_9.Enable = 'off';
            app.Button_9.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_9.Text = 'X';
            app.elem(3,3) = app.x;
            elseif app.currentplayer == app.o
            app.Button_9.Text = 'O';
            app.elem(3,3) = app.o;
            end
            
            
            app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
            
            if app.currentplayer == app.x
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
            elseif app.currentplayer == app.o
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
            end
        end

        % Button pushed function: EndgameButton
        function EndgameButtonPushed(app, event)
            GameOver(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NewGamePanel = uipanel(app.UIFigure);
            app.NewGamePanel.Title = 'New Game';
            app.vsHumanButton = uibutton(app.NewGamePanel, 'push');
            app.vsHumanButton.ButtonPushedFcn = createCallbackFcn(app, @vsHumanButtonPushed, true);
            app.vsHumanButton.Text = 'vs Human';
            app.vsAIButton = uibutton(app.NewGamePanel, 'push');
            app.vsAIButton.ButtonPushedFcn = createCallbackFcn(app, @vsAIButtonPushed, true);
            app.vsAIButton.Text = 'vs AI';
            app.GameOptionsPanel = uipanel(app.UIFigure);
            app.GameOptionsPanel.Title = 'Game Options';
            app.PauseButton = uibutton(app.GameOptionsPanel, 'push');
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.GameOptionsPanel, 'push');
            app.ResumeButton.Text = 'Resume';
            app.EndgameButton = uibutton(app.GameOptionsPanel, 'push');
            app.EndgameButton.ButtonPushedFcn = createCallbackFcn(app, @EndgameButtonPushed, true);
            app.EndgameButton.Text = 'End game';
            app.P1LampLabel = uilabel(app.UIFigure);
            app.P1LampLabel.Text = 'P1';
            app.P1Lamp = uilamp(app.UIFigure);
            app.P2LampLabel = uilabel(app.UIFigure);
            app.P2LampLabel.Text = 'P2';
            app.P2Lamp = uilamp(app.UIFigure);
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Tictactoeapp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end