classdef Tictactoeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        NewGamePanel      matlab.ui.container.Panel
        vsHumanButton     matlab.ui.control.Button
        vsAIButton        matlab.ui.control.Button
        GameOptionsPanel  matlab.ui.container.Panel
        PauseButton       matlab.ui.control.Button
        ResumeButton      matlab.ui.control.Button
        EndgameButton     matlab.ui.control.Button
        P1LampLabel       matlab.ui.control.Label
        P1Lamp            matlab.ui.control.Lamp
        P2LampLabel       matlab.ui.control.Label
        P2Lamp            matlab.ui.control.Lamp
        Button_10         matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_9          matlab.ui.control.Button
    end


    properties (Access = public)
        x = 2;
        o = 1;
        b = -1; % blank
        boardinit;% Description
        currentplayer;
        elem;
        mode;
        robbotbusy
        % Main App
        callingApp;
    end

    methods (Access = public)
    
        function Gameinit(app)
        app.currentplayer = randi(2);
        app.elem = ones(3,3)*app.b;
        app.boardinit = 0;
        EnableafterAI(app);
            if app.currentplayer == app.o
                app.P1Lamp.Color = 'Green';
                app.P2Lamp.Color = 'Red';
            elseif app.currentplayer == app.x
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
            end
%            Setbusy(app,Busy);
            if (app.mode == 2 && app.currentplayer == 1)
                move = AImoved(app);
                UpdateAImove(app,move);
                 %SendMoveToMain(app,'P1',4, 4);
                 
            end
            
        end
 
 function AImove(app)
      if (app.mode == 2 && app.currentplayer == 1)
            %DisableforAI(app);
            move = AImoved(app);
            UpdateAImove(app,move)
            %EnableafterAI(app)
      end
  end
       
        
function pos =  AImoved(app)
found = 0;
while found == 0
for i = 1:2
    
if (1)== i && app.elem(2)==i &&  app.elem(3)==-1
    pos = 3;found=1;break;
elseif app.elem(1)== i && app.elem(2)==-1 &&  app.elem(3)==i
    pos = 2;found=1;break;
elseif app.elem(1)== -1 && app.elem(2)==i &&  app.elem(3)==i
    pos = 1;found=1;break;
elseif app.elem(4)== i && app.elem(5)==i &&  app.elem(6)==-1
    pos = 6;found=1;break;
elseif app.elem(4)== i && app.elem(5)==-1 &&  app.elem(6)==i
    pos = 5;found=1;break;
elseif app.elem(4)== -1 && app.elem(5)==i &&  app.elem(6)==i
    pos = 4;found=1;break;
elseif app.elem(7)== i && app.elem(8)==i &&  app.elem(9)==-1
    pos = 9;found=1;break;
elseif app.elem(7)== i && app.elem(8)==-1 &&  app.elem(9)==i
    pos = 8;found=1;break;
elseif app.elem(7)== -1 && app.elem(8)==i &&  app.elem(9)==i
    pos = 7;found=1;break;
elseif app.elem(1)== i && app.elem(4)==i &&  app.elem(7)==-1
    pos = 7;found=1;break;
elseif app.elem(1)== i && app.elem(4)==-1 &&  app.elem(7)==i
    pos = 4;found=1;break;
elseif app.elem(1)== -1 && app.elem(4)==i &&  app.elem(7)==i
    pos = 1;found=1;break;
elseif app.elem(2)== i && app.elem(5)==i &&  app.elem(8)==-1
    pos = 8;found=1;break;
elseif app.elem(2)== i && app.elem(5)==-1 &&  app.elem(8)==i
    pos = 5;found=1;break;
elseif app.elem(2)== -1 && app.elem(5)==i &&  app.elem(8)==i
    pos = 2;found=1;break;
elseif app.elem(3)== i && app.elem(6)==i &&  app.elem(9)==-1
    pos = 9;found=1;break;
elseif app.elem(3)== i && app.elem(6)==-1 &&  app.elem(9)==i
    pos = 6;found=1;break;
elseif app.elem(3)== -1 && app.elem(6)==i &&  app.elem(9)==i
    pos = 3;found=1;break;
elseif app.elem(1)== i && app.elem(5)==i &&  app.elem(9)==-1
    pos = 9;found=1;break;
elseif app.elem(1)== i && app.elem(5)==-1 &&  app.elem(9)==i
    pos = 5;found=1;break;
elseif app.elem(1)== -1 && app.elem(5)==i &&  app.elem(9)==i
    pos = 1;found=1;break;
elseif app.elem(3)== i && app.elem(5)==i &&  app.elem(7)==-1
    pos = 7;found=1;break;
elseif app.elem(3)== i && app.elem(5)==-1 &&  app.elem(7)==i
    pos = 5;found=1;break;
elseif app.elem(3)== -1 && app.elem(5)==i &&  app.elem(7)==i
    pos = 3;found=1;break;
elseif app.elem(1)==1 && app.elem(2) == -1 && app.elem(3) == -1
    pos = 3;found=1;
elseif app.elem(1)==-1 && app.elem(2) == 1 && app.elem(3) == -1
    pos = 1;found=1;
elseif app.elem(1)==-1 && app.elem(2) == -1 && app.elem(3) == 1
    pos = 1;found=1;
elseif app.elem(1)==1 && app.elem(4) == -1 && app.elem(7) == -1
    pos = 7;found=1;
elseif app.elem(1)==-1 && app.elem(4) == 1 && app.elem(7) == -1
    pos = 1;found=1;
elseif app.elem(1)==-1 && app.elem(4) == -1 && app.elem(7) == 1
    pos = 1;found=1;
elseif app.elem(3)==1 && app.elem(6) == -1 && app.elem(9) == -1
    pos = 9;found=1;
elseif app.elem(3)==-1 && app.elem(6) == 1 && app.elem(9) == -1
    pos = 3;found=1;
elseif app.elem(3)==-1 && app.elem(6) == -1 && app.elem(9) == 1
    pos = 3;found=1;
elseif app.elem(7)==1 && app.elem(8) == -1 && app.elem(9) == -1
    pos = 9;found=1;
elseif app.elem(7)==-1 && app.elem(8) == 1 && app.elem(9) == -1
    pos = 7;found=1;
elseif app.elem(7)==-1 && app.elem(8) == -1 && app.elem(9) == 1
    pos = 7;found=1;
else
    emptyones = find(app.elem==-1);
    if isempty(emptyones)
        pos = 22;
        found = 1;
    else
        pos = emptyones(ceil(rand*length(emptyones)));
        found = 1;
    end
end
end
end
end

function winflag = gamewin(app)
% Player will be 1 or 2
%winflag will be set to various values
% 1 -win
% 0 - Still in play
% 2 - Draw
temp = app.elem(:);
    
if temp(1)==app.currentplayer && temp(4)==app.currentplayer && temp(7)==app.currentplayer
    winflag = 1; 
elseif temp(3)==app.currentplayer && temp(6)==app.currentplayer && temp(9)==app.currentplayer
    winflag = 1;
elseif temp(1)==app.currentplayer && temp(2)==app.currentplayer && temp(3)==app.currentplayer
    winflag = 1;
elseif temp(4)==app.currentplayer && temp(5)==app.currentplayer && temp(6)==app.currentplayer
    winflag = 1;
    elseif temp(2)==app.currentplayer && temp(5)==app.currentplayer && temp(8)==app.currentplayer
    winflag = 1;
elseif  temp(7)==app.currentplayer && temp(8)==app.currentplayer && temp(9)==app.currentplayer
    winflag = 1;
elseif temp(1)==app.currentplayer && temp(5)==app.currentplayer && temp(9)==app.currentplayer
    winflag = 1;
elseif temp(3)==app.currentplayer && temp(5)==app.currentplayer && temp(7)==app.currentplayer
    winflag = 1;
elseif isempty(min(find(temp==-1)))
    winflag = 2;
else
    winflag = 0;
end
end

function DisableforAI(app)
    
    app.Button_10.Enable = 'off';
    app.Button_2.Enable = 'off';
    app.Button_3.Enable = 'off';
    app.Button_4.Enable = 'off';
    app.Button_5.Enable = 'off';
    app.Button_6.Enable = 'off';
    app.Button_7.Enable = 'off';
    app.Button_8.Enable = 'off';
    app.Button_9.Enable = 'off';
end

function EnableafterAI(app)
    if app.elem(1,1) == -1
      app.Button_10.Enable = 'on'; 
    end
    if app.elem(1,2) == -1
      app.Button_2.Enable = 'on'; 
    end
    if app.elem(1,3) == -1
      app.Button_3.Enable = 'on'; 
    end
    if app.elem(2,1) == -1
      app.Button_4.Enable = 'on'; 
    end
    if app.elem(2,2) == -1
      app.Button_5.Enable = 'on';
    end
    if app.elem(2,3) == -1
      app.Button_6.Enable = 'on'; 
    end
    if app.elem(3,1) == -1
      app.Button_7.Enable = 'on'; 
    end
    if app.elem(3,2) == -1
      app.Button_8.Enable = 'on';
    end
    if app.elem(3,3) == -1
      app.Button_9.Enable = 'on'; 
    end
end
 
function UpdateAImove(app,i)
    
    if i == 1
     Button_10Pushed(app, []);   
    end
    if i == 2
     Button_4Pushed(app, []);   
    end
    if i == 3
     Button_7Pushed(app, []);   
    end
    if i == 4
     Button_2Pushed(app, []);   
    end
    if i == 5
     Button_5Pushed(app, []);   
    end
    if i == 6
     Button_8Pushed(app, []);   
    end
    if i == 7
     Button_3Pushed(app, []);   
    end
    if i == 8
     Button_6Pushed(app, []);   
    end
    if i == 9
     Button_9Pushed(app, []);   
    end


end
function LampChange(app)
    if app.currentplayer == app.o
            app.P2Lamp.Color = 'Red';
            app.P1Lamp.Color = 'Green';
    elseif app.currentplayer == app.x
            app.P1Lamp.Color = 'Red';
            app.P2Lamp.Color = 'Green';
    end
            
end

function Playerchange(app)
    app.currentplayer = app.currentplayer + 1;
            if app.currentplayer == 3
                app.currentplayer = 1;
            end
end

function PrintGameresult(app,winflag)
    if winflag == 1
                if app.currentplayer == 1
                uiconfirm(app.UIFigure,'Game Over','Player 1 Won','icon','info');
                pause(4);
                GameOver(app);
                elseif app.currentplayer == 2
                uiconfirm(app.UIFigure,'Game Over','Player 2 Won','icon','info');
                pause(4);
                GameOver(app);
                end         
    elseif winflag == 2
       uiconfirm(app.UIFigure,'Game Over','Tie Game','icon','info');
       pause(4);
       GameOver(app);
    end
end

function GameOver(app)
        
    app.vsAIButton.Enable = 'on';
    app.vsHumanButton.Enable = 'on';
    app.PauseButton.Enable = 'off';
    app.ResumeButton.Enable = 'off';
    app.EndgameButton.Enable = 'off';
    
    app.Button_10.Enable = 'off';
    app.Button_10.BackgroundColor = 'g';
    app.Button_10.Text = '';
    app.Button_2.Enable = 'off';
    app.Button_2.BackgroundColor = 'g';
    app.Button_2.Text = '';
    app.Button_3.Enable = 'off';
    app.Button_3.BackgroundColor = 'g';
    app.Button_3.Text = '';
    app.Button_4.Enable = 'off';
    app.Button_4.BackgroundColor = 'g';
    app.Button_4.Text = '';
    app.Button_5.Enable = 'off';
    app.Button_5.BackgroundColor = 'g';
    app.Button_5.Text = '';
    app.Button_6.Enable = 'off';
    app.Button_6.BackgroundColor = 'g';
    app.Button_6.Text = '';
    app.Button_7.Enable = 'off';
    app.Button_7.BackgroundColor = 'g';
    app.Button_7.Text = '';
    app.Button_8.Enable = 'off';
    app.Button_8.BackgroundColor = 'g';
    app.Button_8.Text = '';
    app.Button_9.Enable = 'off';
    app.Button_9.BackgroundColor = 'g';
    app.Button_9.Text = '';
    
    app.P2Lamp.Color = 'black';
    app.P1Lamp.Color = 'black';
    
end


function SendMoveToMain(app,fromboard,tBPX, tBPY)
    % 'P1' 'Board' 1 2 3 
    tBPZone = 'Board';
    [fBPX,fBPY] = NextAvailBlockTTT(app,fromboard); % p1
    MoveFromBPtoBP(app.callingApp, fromboard, fBPX, fBPY, tBPZone, tBPX, tBPY)
    % 'P1' 'P2'
    
end


function Setbusy(app,Busy)
            app.robotbusy = Busy;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.callingApp = mainApp;
            
        end

        % Button pushed function: vsHumanButton
        function vsHumanButtonPushed(app, event)
            app.vsAIButton.Enable = 'off';
            app.vsHumanButton.Enable = 'off';
            app.mode = 1;
            app.PauseButton.Enable = 'on';
            app.ResumeButton.Enable = 'off';
            app.EndgameButton.Enable = 'on';
            Gameinit(app);
            
            
        end

        % Button pushed function: vsAIButton
        function vsAIButtonPushed(app, event)
            app.vsAIButton.Enable = 'off';
            app.vsHumanButton.Enable = 'off';
            app.mode = 2;
            app.PauseButton.Enable = 'on';
            app.ResumeButton.Enable = 'off';
            app.EndgameButton.Enable = 'on';
           Gameinit(app);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
%% UI changes            
            app.Button_10.Enable = 'off';
            app.Button_10.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_10.Text = 'X';
            app.elem(1,1) = app.x;
            elseif app.currentplayer == app.o
            app.Button_10.Text = 'O';
            app.elem(1,1) = app.o;
            end
 %% Checkwin           
            winflag= gamewin(app);
            
            PrintGameresult(app,winflag);
            
%% Send Data to the Robot   
%{
           if app.currentplayer == 1 
                SendMoveToMain(app,'P1',4, 4);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',4, 4)
           end
           %}
%%computer move or multipayer
		
           if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
           end   


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable = 'off';
            app.Button_2.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_2.Text = 'X';
            app.elem(1,2) = app.x;
            elseif app.currentplayer == app.o
            app.Button_2.Text = 'O';
            app.elem(1,2) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
        %{    
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',4, 5);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',4, 5)
            end
            %}
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Enable = 'off';
            app.Button_3.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_3.Text = 'X';
            app.elem(1,3) = app.x;
            elseif app.currentplayer == app.o
            app.Button_3.Text = 'O';
            app.elem(1,3) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
            %{
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',4, 6);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',4, 6)
            end
            %}
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.Enable = 'off';
            app.Button_4.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_4.Text = 'X';
            app.elem(2,1) = app.x;
            elseif app.currentplayer == app.o
            app.Button_4.Text = 'O';
            app.elem(2,1) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
             %{
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',5, 4);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',5, 4)
           end
            %}
           
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Button_5.Enable = 'off';
            app.Button_5.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_5.Text = 'X';
            app.elem(2,2) = app.x;
            elseif app.currentplayer == app.o
            app.Button_5.Text = 'O';
            app.elem(2,2) = app.o;
            end
            
            winflag= gamewin(app);
            
           PrintGameresult(app,winflag)
            %{
           if app.currentplayer == 1 
                SendMoveToMain(app,'P1',5, 5);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',5, 5)
           end
           %}
           if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
           end  
           
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Button_6.Enable = 'off';
            app.Button_6.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_6.Text = 'X';
            app.elem(2,3) = app.x;
            elseif app.currentplayer == app.o
            app.Button_6.Text = 'O';
            app.elem(2,3) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
             %{
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',5, 6);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',5, 6)
            end
            %}
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
           
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Button_7.Enable = 'off';
            app.Button_7.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_7.Text = 'X';
            app.elem(3,1) = app.x;
            elseif app.currentplayer == app.o
            app.Button_7.Text = 'O';
            app.elem(3,1) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
             %{
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',6, 4);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',6, 4)
            end
            %}
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Button_8.Enable = 'off';
            app.Button_8.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_8.Text = 'X';
            app.elem(3,2) = app.x;
            elseif app.currentplayer == app.o
            app.Button_8.Text = 'O';
            app.elem(3,2) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
             %{
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',6, 5);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',6, 5)
            end
            %}
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
           
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Button_9.Enable = 'off';
            app.Button_9.BackgroundColor = 'r';
            if app.currentplayer == app.x
            app.Button_9.Text = 'X';
            app.elem(3,3) = app.x;
            elseif app.currentplayer == app.o
            app.Button_9.Text = 'O';
            app.elem(3,3) = app.o;
            end
            
             winflag= gamewin(app);
            
            PrintGameresult(app,winflag)
             %{
            if app.currentplayer == 1 
                SendMoveToMain(app,'P1',6, 6);
           elseif app.currentplayer == 2
               SendMoveToMain(app,'P2',6, 6)
            end
            %}
            if winflag == 0  
                Playerchange(app);
                LampChange(app);
                AImove(app);
            end  
            
            
        end

        % Button pushed function: EndgameButton
        function EndgameButtonPushed(app, event)
          %  TTTEndGame(app.callingApp);
            GameOver(app);
            
        end

        % Close request function: UIFigure
        function CloseTictactoe(app, event)
%            TTTClose(app.callingApp);
            pause (2);
            delete(app)
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %TTTPause(app);
            app.ResumeButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            %TTTResume(app);
            app.PauseButton.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseTictactoe, true);
            app.NewGamePanel = uipanel(app.UIFigure);
            app.NewGamePanel.Title = 'New Game';
            app.vsHumanButton = uibutton(app.NewGamePanel, 'push');
            app.vsHumanButton.ButtonPushedFcn = createCallbackFcn(app, @vsHumanButtonPushed, true);
            app.vsHumanButton.Text = 'vs Human';
            app.vsAIButton = uibutton(app.NewGamePanel, 'push');
            app.vsAIButton.ButtonPushedFcn = createCallbackFcn(app, @vsAIButtonPushed, true);
            app.vsAIButton.Text = 'vs AI';
            app.GameOptionsPanel = uipanel(app.UIFigure);
            app.GameOptionsPanel.Title = 'Game Options';
            app.PauseButton = uibutton(app.GameOptionsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.GameOptionsPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.EndgameButton = uibutton(app.GameOptionsPanel, 'push');
            app.EndgameButton.ButtonPushedFcn = createCallbackFcn(app, @EndgameButtonPushed, true);
            app.EndgameButton.Text = 'End game';
            app.P1LampLabel = uilabel(app.UIFigure);
            app.P1LampLabel.Text = 'P1';
            app.P1Lamp = uilamp(app.UIFigure);
            app.P2LampLabel = uilabel(app.UIFigure);
            app.P2LampLabel.Text = 'P2';
            app.P2Lamp = uilamp(app.UIFigure);
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Tictactoeapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end