classdef Assignment2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OverviewTab                   matlab.ui.container.Tab
        JointAngleTable               matlab.ui.control.Table
        CurrentJointAnglesLabel       matlab.ui.control.Label
        EEPositionTable               matlab.ui.control.Table
        EEPositionLabel               matlab.ui.control.Label
        EEOrientationTable            matlab.ui.control.Table
        EEOrientationLabel            matlab.ui.control.Label
        DIOStatesLabel                matlab.ui.control.Label
        PumpButton                    matlab.ui.control.StateButton
        VacuumLabel                   matlab.ui.control.Label
        SolenoidButton                matlab.ui.control.StateButton
        ConveyorLabel                 matlab.ui.control.Label
        ConRunButton                  matlab.ui.control.StateButton
        DirectionButtonGroup          matlab.ui.container.ButtonGroup
        IntocellButton                matlab.ui.control.ToggleButton
        OutofcellButton               matlab.ui.control.ToggleButton
        TabGroup2                     matlab.ui.container.TabGroup
        SentTab                       matlab.ui.container.Tab
        SentLog                       matlab.ui.control.TextArea
        ReceivedTab                   matlab.ui.container.Tab
        ReceivedLog                   matlab.ui.control.TextArea
        QueuedTab                     matlab.ui.container.Tab
        QueuedLog                     matlab.ui.control.TextArea
        MessageLogLabel               matlab.ui.control.Label
        RobotStatusLabel              matlab.ui.control.Label
        StatusLampLabel               matlab.ui.control.Label
        ConStatLamp                   matlab.ui.control.Lamp
        TabVidAxes                    matlab.ui.control.UIAxes
        ConVidAxes                    matlab.ui.control.UIAxes
        PoseTab                       matlab.ui.container.Tab
        MovetoPositionPanel           matlab.ui.container.Panel
        RelativetoHomeButtonGroup     matlab.ui.container.ButtonGroup
        TableButton                   matlab.ui.control.ToggleButton
        ConveyorButton                matlab.ui.control.ToggleButton
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        RollEditFieldLabel            matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        YawEditField                  matlab.ui.control.NumericEditField
        EndEffectorOrientationOnlySwitchLabel  matlab.ui.control.Label
        EndEffOriOnlySwitch           matlab.ui.control.Switch
        SendPositionCommandButton     matlab.ui.control.Button
        MovetoPosePanel               matlab.ui.container.Panel
        Joint1EditFieldLabel          matlab.ui.control.Label
        Joint1EditField               matlab.ui.control.NumericEditField
        Joint2EditFieldLabel          matlab.ui.control.Label
        Joint2EditField               matlab.ui.control.NumericEditField
        Joint3EditFieldLabel          matlab.ui.control.Label
        Joint3EditField               matlab.ui.control.NumericEditField
        Joint4EditFieldLabel          matlab.ui.control.Label
        Joint4EditField               matlab.ui.control.NumericEditField
        Joint5EditFieldLabel          matlab.ui.control.Label
        Joint5EditField               matlab.ui.control.NumericEditField
        Joint6EditFieldLabel          matlab.ui.control.Label
        Joint6EditField               matlab.ui.control.NumericEditField
        SendPoseCommandButton         matlab.ui.control.Button
        SpeedDropDownLabel            matlab.ui.control.Label
        SpeedDropDown                 matlab.ui.control.DropDown
        JoggingTab                    matlab.ui.container.Tab
        JogLinearPanel                matlab.ui.container.Panel
        RelativetoFrameButtonGroup    matlab.ui.container.ButtonGroup
        BaseButton                    matlab.ui.control.ToggleButton
        EndEffectorButton             matlab.ui.control.ToggleButton
        AxisButtonGroup               matlab.ui.container.ButtonGroup
        XButton                       matlab.ui.control.ToggleButton
        YButton                       matlab.ui.control.ToggleButton
        ZButton                       matlab.ui.control.ToggleButton
        JogButton                     matlab.ui.control.Button
        JogJointPanel                 matlab.ui.container.Panel
        JointButtonGroup              matlab.ui.container.ButtonGroup
        Button                        matlab.ui.control.ToggleButton
        Button_2                      matlab.ui.control.ToggleButton
        Button_3                      matlab.ui.control.ToggleButton
        Button_4                      matlab.ui.control.ToggleButton
        Button_5                      matlab.ui.control.ToggleButton
        Button_6                      matlab.ui.control.ToggleButton
        JogButton_2                   matlab.ui.control.Button
        VideoTab                      matlab.ui.container.Tab
        OpenTableCameraFeedButton     matlab.ui.control.Button
        OpenConveyorCameraFeedButton  matlab.ui.control.Button
        ShutdownTab                   matlab.ui.container.Tab
        MovetohomeButton              matlab.ui.control.Button
        ShutdownProcedureLabel        matlab.ui.control.Label
        SetallDIOto0Button            matlab.ui.control.Button
        DebugTab                      matlab.ui.container.Tab
        MainfunctiontickLampLabel     matlab.ui.control.Label
        TickLamp                      matlab.ui.control.Lamp
        VideofunctiontickLampLabel    matlab.ui.control.Label
        VideoTickLamp                 matlab.ui.control.Lamp
        PauseButton                   matlab.ui.control.StateButton
        ErrorStateText                matlab.ui.control.EditField
        ERRORLampLabel                matlab.ui.control.Label
        ERRORLamp                     matlab.ui.control.Lamp
        CancelButton                  matlab.ui.control.Button
        ContinueButton                matlab.ui.control.Button
    end


    properties (Access = private)
        % Timer used for tick callback fucntion
        tickTimer;
        tickPeriod = 0.5; % Period of tick timer
        
        % Timer used for video update
        videoTickTimer;
        videoTickPeriod = 0.5; % Period of video tick timer
        
        % List of data objects
        % Should always reflect the current status as reported from robot studio
        
        % Video objects
        vidTable;
        vidConveyor;
        % Video Handles
        TabVidHandleOverview;
        ConVidHandleOverview;
        TabVidHandleWindow;
        ConVidHandleWindow;
        TabPlotHandle;
        ConPlotHandle;
        TabVidAxesWindow;
        ConVidAxesWindow;
        % Window open flags
        TabVidWindow = 0;
        ConVidWindow = 0;
        % Window figs
        tabFig;
        conFig;
        
        % List of status data objects
        % Should always reflect the current status as reported from robot studio
        
        % Joint position objects
        joint1Pos = 0;
        joint2Pos = 0;
        joint3Pos = 0;
        joint4Pos = 0;
        joint5Pos = 0;
        joint6Pos = 0;
        
        % End effector position and orientation objects
        endEffectorX = 0;
        endEffectorY = 0;
        endEffectorZ = 0;
        endEffectorRoll = 0;
        endEffectorPitch = 0;
        endEffectorYaw = 0;
        
        % DIO states (0 = off, 1 = on)
        vacPump = 0; 
        vacSol = 0;
        conStat = 0;
        conRun = 0;
        conDir = 0;  % (0 = InToCell, 1 = OutOfCell)
        
        % DIO Requests (0 = off, 1 = on)
        vacPumpReq = 0; 
        vacSolReq = 0;
        conRunReq = 0;
        conDirReq = 0;  % (0 = InToCell, 1 = OutOfCell)
        
        % Pause, continue & cancel
        pauseButton;
        continueButton;
        cancelButton;
        
        % Message Log Objects
        % Counters used to append new message to bottom of log
        sentMsgCount = 1;
        receivedMsgCount = 1;
        queuedMsgCount = 1;
        
        % Pose Tab data objects
        endEffOriOnly;
        
        % Joint immediate angles for pose control
        Jimm = zeros(1,6);
        
        % IRB_120 for kinematics
        irb_120;
        
        % the commandQueue and communications
        queue = [];
        % intermediate stage pose
        Jim = zeros(1,6);
        socket = 0;
        connectType = 2;
        %
        watchdogTimer = 0;
        initFlag = 0;
        Disconnected = 0;
        
        safety = [0 0 0 0];
        
        debugFlag = 1;
        
        % Toggle set in tick (dubug only)
        tickToggle;
        tickToggle2;

        errorFlag % Description
    end

    methods (Access = private)
        
        % Function for writing to the message logs
        function [] = WriteToLog(app,log,msg)
            switch log
                case 'S'
                    app.SentLog.Value{app.sentMsgCount} = msg;
                    app.sentMsgCount = app.sentMsgCount + 1;
                case 'R'
                    app.ReceivedLog.Value{app.receivedMsgCount} = msg;
                    app.receivedMsgCount = app.receivedMsgCount + 1;
                case 'Q'
                    app.QueuedLog.Value{app.queuedMsgCount} = msg;
                    app.queuedMsgCount = app.queuedMsgCount + 1;
                otherwise
                    error('Incorrect log string used');
            end
        end
        
        % Function to write string msg to error state text box and light error lamp
        function [] = WriteError(app,msg,colour)
            app.ErrorStateText.Value = msg;
            app.ERRORLamp.Color = colour;
        end
        
        % Function to clear error state text box and error lamp
        function [] = ClearError(app)
            app.ErrorStateText.Value = '';
            app.ERRORLamp.Color = 'white';
        end
        
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        % Tick Callback function (MAIN LOOP)
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function TickCallback(app,~,~)
            
%             if (app.initFlag == 0)
%                 [app.socket, app.connectType] = startConnectionToRobot();
%                 if (app.connectType == 2) %2 is an error
%                     AppClose(app);
%                 end
%                 app.initFlag = 1;
%             end
%             disp('n')
            
%             if (app.initFlag == 1)
%                 % Try to send to robot, if it fails ask for restart
%                 try
%                   [app.queue, out] = sendToRobot(app.queue, app.socket, app.errorFlag);
%                 catch
%                     disp('Connection lost');
%                     % Popup a connection error
%                     response = connectionPopUp();
%                     % If response is 0 then close the app
%                     if (response == 0)
%                         AppClose(app);
%                     else
%                         % Otherwise reset init flag to reconnect
%                         app.initFlag = 0;
%                         CloseConnection(app)
%                         return; % No point running rest of tick
%                     end
%                 end
%                 [status, app.safety, DIO, joints] = processMessage(out);
%                 
%                
%                 [app.joint1Pos, app.joint2Pos, app.joint3Pos, ...
%                     app.joint4Pos, app.joint5Pos, app.joint6Pos] = updateJoints(joints);
%                 
%                 [app.vacPump, app.vacSol, app.conStat, ...
%                                     app.conRun, app.conDir] = updateDIO(DIO);
%             end
            
            % Error flag
%             app.errorFlag = SafetySystem(app,app.safety);
            
            % Update the GUI with DIO
            UpdateDOIGUI(app);
            
            % Update the end effector member data
            try
                [app.EndEffectorX, app.EndEffectorY, app.EndEffectorZ, ...
                    app.EndEffectorRoll, app.EndEffectorPitch, app.EndEffectorYaw] = UpdateEndEffector(app);
            catch
                disp('End effector calc error');
            end
            
            % Update the robot status with values
            UpdateStatusGUI(app);
            
            % For debugging (flashes lamp on debug page)
            if (app.tickToggle == 1)
                app.TickLamp.Color = 'white';
                app.tickToggle = 0;
            else
                app.TickLamp.Color = 'green';
                app.tickToggle = 1;
            end
        end
        
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        % Video Tick Callback function
        %~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        function VideoTickCallback(app,~,~)
            % Try disp table video
            %try
                % Get the snapshot
                imageTab = getsnapshot(app.vidTable);
                % If it should be shown in window do that, else show in oveview
                if (app.TabVidWindow == 1)
                    set(app.TabVidHandleWindow, 'CData', imageTab);
                    disp('A');
                    processTabImages(imageTab,app.TabPlotHandle,app.TabVidAxesWindow)
                    disp('B');
                else
                    set(app.TabVidHandleOverview, 'CData', imageTab);
                end
            %catch
                %disp('Error in table video tick');
            %end
            % Try disp conveyor video
            %try
                % Get the snapshot
                imageCon = getsnapshot(app.vidConveyor);
                % If it should be shown in window do that, else show in oveview
                if (app.ConVidWindow == 1)
                    set(app.ConVidHandleWindow, 'CData', imageCon);
                    processConImages(imageCon,app.ConPlotHandle,app.ConVidAxesWindow)
                else
                    set(app.ConVidHandleOverview, 'CData', imageCon);
                end
            %catch
                %disp('Error in conveyor video tick');
            %end
            
            % For debugging (flashes lamp on debug page)
            if (app.tickToggle2 == 1)
                app.VideoTickLamp.Color = 'white';
                app.tickToggle2 = 0;
            else
                app.VideoTickLamp.Color = 'red';
                app.tickToggle2 = 1;
            end
        end
        
        % Close Video Figure callback 
        function  VidFigCloseReq(app,fig,VidName,fig2,~)
            try
                %selection = questdlg('Close the window?','Confirmation','Yes','No','Yes');
                %switch selection
                    %case 'Yes'
                        if (strcmp(VidName, 'Table'))
                            % Restart the table overview video
                            app.TabVidWindow = 0;
                        elseif (strcmp(VidName, 'Conveyor'))
                            % Restart the table overview video
                            app.ConVidWindow = 0;
                        end
                            delete(fig)
                    %case 'No'
                    %return
                %end
            catch
                delete(fig2)
            end
        end
        
        % Close App Figure? 
        % If the user confirms then stop the timers and then delete the app
        function  AppCloseReq(app)
            selection = questdlg('Close the app?','Confirmation','Yes','No','Yes');
            switch selection
                case 'Yes'
                    % Stop times, video feeds, figures, then delete the app
                    AppClose(app);
                case 'No'
                return
            end
        end
        
        % Hard close app
        function AppClose(app)
            CloseConnection(app);
            StopTimers(app);
            StopVideos(app);
            CloseFigs(app);
            delete(app);
        end
        
        % Close socket connection
        function CloseConnection(app)
            try
                if app.socket ~= 0
                closeConnection(app.socket);         
                end
                
            catch
                disp('Socket close failed');
            end
        end
        
        % Start the requested overview video
        function StartOverviewVideo(app,VidName)
            if (strcmp(VidName, 'Table'))
                % Prep video object for manual and start it
                app.vidTable = videoinput('winvideo', 1, 'MJPG_1600x1200');
                %app.vidTable.ReturnedColorSpace = 'rgb';
                triggerconfig(app.vidTable, 'manual');
                start(app.vidTable);
                % Prep axis handle
                video_resolution1 = app.vidTable.VideoResolution;
                nbands1 = app.vidTable.NumberOfBands;
                app.TabVidHandleOverview = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.TabVidAxes);
            elseif (strcmp(VidName, 'Conveyor'))
                % Prep video object for manual and start it
                app.vidConveyor = videoinput('winvideo', 2, 'MJPG_1600x1200');
                %app.vidConveyor.ReturnedColorSpace = 'rgb';
                triggerconfig(app.vidConveyor, 'manual');
                start(app.vidConveyor);
                % Prep axis handle
                video_resolution2 = app.vidConveyor.VideoResolution;
                nbands2 = app.vidConveyor.NumberOfBands;
                app.ConVidHandleOverview = imshow(zeros([video_resolution2(2), video_resolution2(1), nbands2]), 'Parent', app.ConVidAxes);
            else
                disp('Error in start requested video name');
            end
        end
        
        % Function for gracefully kill all timers. (Thanks to the god that is Image Analyst on mathworks forums)
        function StopTimers(app)
            try
            	%fprintf('Entering StopTimer...\n');
            	listOfTimers = timerfindall; % List all timers, just for info.
            	% Get handle to the one timer that we should have.
            	if isempty(listOfTimers)
            		% Exit if there is no timer to turn off.
            		%fprintf('There are no timers to turn off. Leaving StopTimer().\n');
            		return;
            	end
            	% Stop that timer.
            	stop(app.tickTimer);
                delete(app.tickTimer);
            	% Delete all timers from memory.
            	listOfTimers = timerfindall;
            	if ~isempty(listOfTimers)
                        stop(listOfTimers(:));
            		delete(listOfTimers(:));
            	end
            	%fprintf('Left StopTimer and turned off all timers.\n');
            catch ME
            	errorMessage = sprintf('Error in StopTimer().\nThe error reported by MATLAB is:\n\n%s', ME.message);
            	fprintf('%s\n', errorMessage);
            end
        end
        
        % Stop the video by reset
        function StopVideos(app)
            imaqreset;
        end
        
        % Close all figs
        function CloseFigs(app)
            % If its currently open, close it
            if (app.TabVidWindow == 1)
                delete(app.tabFig);
            end
            if (app.ConVidWindow == 1)
                delete(app.conFig);
            end
        end
        
        % Close request for SWP fig
        function SWPCancel(app)
            % Stop times, video feeds, figures, then delete the app
            StopTimers(app);
            StopVideos(app);
            delete(app);

        end
        
        function ShowSWP(app)
            SWP  = fileread('SWP.txt');
            answer = uiconfirm(app.UIFigure,SWP,'Safe Work Procedures','Icon','info','Options',{'I Accept','Cancel'},'DefaultOption',1,'CancelOption',2);
            if (strcmp(answer, 'Cancel'))
                SWPCancel(app);
            end
        end

        % Init the DH prams for IRB_120
        function initIRB120(app)
           L(1) = Link('revolute', 'd', 0.29, 'a', 0, 'alpha', -pi/2, 'qlim', [-165 165].*pi/180);
           L(2) = Link('revolute', 'd', 0, 'a', 0.27, 'alpha', 0, 'offset', -pi/2, 'qlim', [-110 110].*pi/180);
           L(3) = Link('revolute', 'd', 0, 'a', 0.07, 'alpha', -pi/2, 'qlim', [-110 70].*pi/180);
           L(4) = Link('revolute', 'd', 0.302, 'a', 0, 'alpha', pi/2, 'qlim', [-160 160].*pi/180);
           L(5) = Link('revolute', 'd', 0, 'a', 0, 'alpha', pi/2, 'offset', pi, 'qlim', [-120 120].*pi/180);
           L(6) = Link('revolute', 'd', 0.137, 'a', 0, 'alpha', 0, 'qlim', [-400 400].*pi/180);
           app.irb_120 = SerialLink(L, 'name', 'IRB120');
        end
        
        % Update the gui with the current state of the DIOs
        function UpdateDOIGUI(app)
            UpdateVacPump(app);
            UpdateVacSol(app);
            UpdateConStat(app);
            UpdateConRun(app);
            UpdateConDir(app);
        end
        
        % Update gui on vac pump
        function UpdateVacPump(app)
            % Change the button colour
            if (app.vacPump == 1)
                app.PumpButton.BackgroundColor = 'green';
                
                % Enable the solenoid button
                app.SolenoidButton.Enable = 'on';
                
            else
                app.PumpButton.BackgroundColor = 'red';
                
                % Disable the solenoid button if it is already off
                if (app.vacSol == 0)
                    app.SolenoidButton.Enable = 'off';
                    app.SolenoidButton.BackgroundColor = 'red';
                else % Request it to be turned off
                    [~, app.queue] = toggleDIO('2',app.queue);
                end
            end
        end
        
        % Update gui on vac solinoid
        function UpdateVacSol(app)
            % Set the colour
            if (app.vacSol == 1)
                app.SolenoidButton.BackgroundColor = 'green';
            else
                app.SolenoidButton.BackgroundColor = 'red';
            end
        end
        
        % Update the gui on conveyor status
        function UpdateConStat(app)
            % Set the colour
            if (app.conStat == 1)
                app.ConStatLamp.Color = 'green';
                
                % Enable the conRun button
                app.ConRunButton.Enable = 'on';
            else
                app.ConStatLamp.Color = 'red';
                
                % Disable the conRun button if it is already off
                if (app.conRun == 0)
                    app.ConRunButton.Enable = 'off';
                    app.ConRunButton.BackgroundColor = 'red';
                else % Request it to be turned off
                    [~, app.queue] = toggleDIO('3',app.queue);
                end
            end
        end
        
        % Update the gui on conveyor run
        function UpdateConRun(app)
            % Set the colour
            if (app.conRun == 1)
                app.ConRunButton.BackgroundColor = 'green';
            else
                app.ConRunButton.BackgroundColor = 'red';
            end
        end
        
        % Update the gui on conveyor dir
        function UpdateConDir(app)
            if (app.conDir == 0) % In to cell
                app.IntocellButton.Value = 1;
            else % Out of cell
                app.OutofcellButton.Value = 1;
            end
        end
        
        % Fill robot arm status/position tables with current status
        function UpdateStatusGUI(app)
            app.JointAngleTable.Data = {'J1', app.joint1Pos; 'J2', app.joint2Pos; 'J3', app.joint3Pos; 'J4', app.joint4Pos; 'J5', app.joint5Pos; 'J6', app.joint6Pos};  
            app.EEPositionTable.Data = {'X', app.endEffectorX; 'Y', app.endEffectorY; 'Z', app.endEffectorZ};
            app.EEOrientationTable.Data = {'Roll', app.endEffectorRoll; 'Pitch', app.endEffectorPitch; 'Yaw', app.endEffectorYaw};
        end
        
        % Update the status of the end effector using forward kinematics
        function [x,y,z,roll,pitch,yaw] = UpdateEndEffector(app)
            k = pi/180;
            J(1) = app.Joint1Pos * k;
            J(2) = app.Joint2Pos * k;
            J(3) = app.Joint3Pos * k;
            J(4) = app.Joint4Pos * k;
            J(5) = app.Joint5Pos * k;
            J(6) = app.Joint6Pos * k;
            T = app.irb_120.fkine(J);
            rotm = [T.n,T.o,T.a];
            temp = T.t;
            x = temp(1);
            y = temp(2);
            z = temp(3);
            if (rotm(1,1) == 0)
                roll = 0;
                pitch = atan2(-rotm(1,3)/sqrt((rotm(2,3))^2 +(rotm(3,3))^2));
                yaw = atan2(rotm(2,3),rotm(3,3));
                return;
            end
            roll = atan2(rotm(1,2),rotm(1,1));
            pitch = atan2(-rotm(1,3)/sqrt((rotm(2,3))^2 +(rotm(3,3))^2));
            yaw = atan2(rotm(2,3),rotm(3,3));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Start the video feeds
            % Table Camera
            try
                StartOverviewVideo(app, 'Table')
            catch
                warning('Problem starting table camera');
            end
            % Conveyor Camera
            %try
                StartOverviewVideo(app, 'Conveyor')
            %catch 
                %warning('Problem starting conveyor camera');
            %end

            % End all timers in memory
            %delete(timerfind);
            
            % Init the robot arm status/position tables
            app.JointAngleTable.Data = {'J1', app.joint1Pos; 'J2', app.joint2Pos; 'J3', app.joint3Pos; 'J4', app.joint4Pos; 'J5', app.joint5Pos; 'J6', app.joint6Pos};  
            app.EEPositionTable.Data = {'X', app.endEffectorX; 'Y', app.endEffectorY; 'Z', app.endEffectorZ};
            app.EEOrientationTable.Data = {'Roll', app.endEffectorRoll; 'Pitch', app.endEffectorPitch; 'Yaw', app.endEffectorYaw};
            
            % Init the DOI buttons
            app.PumpButton.Value = 0;
            app.PumpButton.BackgroundColor = 'red';
            app.SolenoidButton.Value = 0;
            app.SolenoidButton.Enable = 'off';
            app.SolenoidButton.BackgroundColor = 'red';
%             app.ConStatusButton.Value = 0;
%             app.ConStatusButton.BackgroundColor = 'red';
            app.ConRunButton.Value = 0;
            app.ConRunButton.Enable = 'off';
            app.ConRunButton.BackgroundColor = 'red';
            app.IntocellButton.Value = 1;
            
            % Init the message logs
            message = 'This is the beginning of the sent message log';
            WriteToLog(app, 'S', message);
            message = 'This is the beginning of the received message log';
            WriteToLog(app, 'R', message);
            message = 'This is the beginning of the queued message log';
            WriteToLog(app, 'Q', message);
            
            % Init continue button
            app.ContinueButton.Enable = 'Off';

            % Init the timer for the tick callback function
            app.tickTimer = timer('StartDelay', 5, 'Period', app.tickPeriod,'ExecutionMode', 'fixedRate','TasksToExecute', Inf,'BusyMode','drop');
            app.tickTimer.TimerFcn = @(obj,event)app.TickCallback(app);
            start(app.tickTimer);
            
            % Init the tick toggle
            app.tickToggle = 0;
            
            % Init the timer for the video tick callback function
            app.videoTickTimer = timer('StartDelay', 6, 'Period', app.videoTickPeriod,'ExecutionMode', 'fixedRate','TasksToExecute', Inf,'BusyMode','drop');
            app.videoTickTimer.TimerFcn = @(obj,event)app.VideoTickCallback(app);
            start(app.videoTickTimer);
            
            %Init the corke toolbox
            startup_rvc;
            
            % Init the irb_120
            initIRB120(app);
            
            % Show SWP and prompt for confirmation
            ShowSWP(app);
        end

        % Value changed function: PumpButton
        function PumpButtonValueChanged(app, event)
            value = app.PumpButton.Value;
            if (value == 1)
                [~, app.queue] = toggleDIO('1',app.queue);
            end
        end

        % Value changed function: SolenoidButton
        function SolenoidButtonValueChanged(app, event)
            value = app.SolenoidButton.Value;
            % Only change the sol button if the pump is running
            if (app.vacPump == 0)
                app.SolenoidButton.Value = 0;
                
            else
                [~, app.queue] = toggleDIO('2',app.queue);

                
            end

        end

        % Value changed function: ConRunButton
        function ConRunButtonValueChanged(app, event)
            value = app.ConRunButton.Value;
            
            % Only request a change the value if Con Stat is 1
            if (app.conStat == 0)
                app.ConRunButton.Value = 0;
            else
                [~, app.queue] = toggleDIO('3',app.queue);
            end
        end

        % Selection changed function: DirectionButtonGroup
        function DirectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.DirectionButtonGroup.SelectedObject;
            
            if (selectedButton == app.IntocellButton)
                % THIS IS FUCKED
                [~, app.queue] = toggleDIO('4',app.queue);
            elseif (selectedButton == app.OutofcellButton)
                [~, app.queue] = toggleDIO('4',app.queue);
                % ---------------
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            
            app.pauseButton = value;
            
            if (app.pauseButton == 1)
                app.PauseButton.BackgroundColor = 'red';
                app.ContinueButton.Enable = 'Off';
                app.ContinueButton.BackgroundColor = 'white';
                % Call pause function here
                
            else
                app.PauseButton.BackgroundColor = 'white';
                app.ContinueButton.Enable = 'On';
                app.ContinueButton.BackgroundColor = 'yellow';
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.continueButton = 1;
            if (app.continueButton == 1)
                % Call continue function here (Should return 0 on success, 1 on failure)
                % ret = ContinueFunction()
                ret = 0;
                if (ret == 0)
                    app.ContinueButton.BackgroundColor = 'white';
                    app.ContinueButton.Enable = 'Off';
                else
                    % Continue failed
                    % Do nothing? Write to log?
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Add cancel motion task function call here
            % ret = CancelMotionTask()
        end

        % Value changed function: EndEffOriOnlySwitch
        function EndEffOriOnlySwitchValueChanged(app, event)
            value = app.EndEffOriOnlySwitch.Value;
            
            app.endEffOriOnly = value;
            
            if (strcmp(app.endEffOriOnly,'On'))
                app.XEditField.Enable = 'Off';
                app.YEditField.Enable = 'Off';
                app.ZEditField.Enable = 'Off';
            else
                app.XEditField.Enable = 'On';
                app.YEditField.Enable = 'On';
                app.ZEditField.Enable = 'On';
            end
        end

        % Button pushed function: SendPositionCommandButton
        function SendPositionCommandButtonPushed(app, event)
            % Call move to position command/change orientation function
            % Read values from GUI
            Tableval = app.TableButton.Value;
            Conveyerval = app.ConveyorButton.Value;
            orientationval = app.EndEffOriOnlySwitch.Value;
            Speed = app.SpeedDropDown.Value;
            if orientationval == 'On'
                Orientationval = 1;
            end
            k = pi/180;
            roll = app.RollEditField.Value * k;
            pitch = app.PitchEditField.Value*k;
            yaw = app.YawEditField.Value*k;
            % Rotation matrix
            Rx = [1,0,0;0,cos(roll),-sin(roll);0,sin(roll),cos(roll)];
            Ry = [cos(pitch),0,sin(pitch);0,1,0;-sin(pitch),0,cos(pitch)];
            Rz = [cos(yaw),-sin(yaw),0;sin(yaw),cos(yaw),0;0,0,1];
            Rotm = Rx*Ry*Rz;
            % Relative to table: position and orientation
            if (Tableval == 1 && Conveyerval == 0 && Orientationval == 0)
                HomeT = [0,1,0,175;1,0,0,0;0,0,-1,147;0,0,0,1];
                X = app.XEditField.Value;
                Y = app.YEditField.Value;
                Z = app.ZEditField.Value;
                EfT = [Rotm(1,:),X;Rotm(2,:),Y;Rotm(3,:),Z;0,0,0,1];
                EffT = HomeT * EfT;
            end
            % Relative to conveyor: position and orientation
            if (Conveyerval == 1 && Tableval == 0 && Orientationval == 0)
                HomeT = [-1,0,0,0;0,1,0,409;0,0,1,22;0,0,0,1];
                X = app.XEditField.Value;
                Y = app.YEditField.Value;
                Z = app.ZEditField.Value;
                EfT = [Rotm(1,:),X;Rotm(2,:),Y;Rotm(3,:),Z;0,0,0,1];
                EffT = HomeT * EfT;
            end
            % Orientation only
            if (Orientationval == 1)
                X = app.endEffectorX;
                Y = app.endEffectorY;
                Z = app.endEffectorZ;
                EffT = [Rotm(1,:),X;Rotm(2,:),Y;Rotm(3,:),Z;0,0,0,1];
            end
            % Inverse kinematics and normalise
            app.Jimm = app.irb_120.ikine(EffT);
            for i = 1:length(app.Jimm)
                if app.Jimm(i) > 360
                   app.Jimm(i) = J(i) - 360;
                end
            end
            % Convert to char for comms
            for i = 1: length(app.Jimm)
                app.Jimm(i) = (app.Jimm(i) / (360)) * ((2^15));
            end
            app.Jimm = [int2chars(app.Jimm(1)) int2chars(app.Jimm(2)) int2chars(app.Jimm(3)) int2chars(app.Jimm(4)) int2chars(app.Jimm(5)) int2chars(app.Jimm(6))];
            outData = [Speed app.Jimm];
            % Create command string
            command = createCmdString(outData);
            % Write to queue
            app.queue = sendToQueue(app.queue, command);
        end

        % Button pushed function: SendPoseCommandButton
        function SendPoseCommandButtonPushed(app, event)
            % Read in data from GUI
            app.Jimm(1) = app.Joint1EditField.Value;
            app.Jimm(2) = app.Joint2EditField.Value;
            app.Jimm(3) = app.Joint3EditField.Value;
            app.Jimm(4) = app.Joint4EditField.Value;
            app.Jimm(5) = app.Joint5EditField.Value;
            app.Jimm(6) = app.Joint6EditField.Value;
            Speed = app.SpeedDropDown.Value;
            
            switch Speed
                case '25'
                Speed = '4';
                case '50'
                Speed = '3';  
                case '75'
                Speed = '2';
                case '100'    
                Speed = '1';
            end
            
            % Normalise to 360deg
            for i = 1:length(app.Jimm)
                if app.Jimm(i) > 360
                    app.Jimm(i) = J(i) - 360;
                end
            end
            % Convert to char for comms
            for i = 1: length(app.Jimm)
                app.Jimm(i) = (app.Jimm(i) / (360)) * ((2^15));
            end
            app.Jimm = [int2chars(app.Jimm(1)) int2chars(app.Jimm(2)) int2chars(app.Jimm(3)) int2chars(app.Jimm(4)) int2chars(app.Jimm(5)) int2chars(app.Jimm(6))];
            outData = [Speed app.Jimm];
            % Create command string
            command = createCmdString(outData);
            % Write to queue
            app.queue = sendToQueue(app.queue, command);
        end

        % Button pushed function: OpenTableCameraFeedButton
        function OpenTableCameraFeedButtonPushed(app, event)
            % Prepare the video to be shown in the new figure
            app.tabFig = uifigure('Name','Table Camera Feed','CloseRequestFcn',@(fig, event)VidFigCloseReq(app,app.tabFig,'Table'),'Position',[50 50 800 600]); %
            app.TabVidAxesWindow = uiaxes(app.tabFig,'Position',[10,10,780,580]);
            try
                video_resolution1 = app.vidTable.VideoResolution;
                nbands1 = app.vidTable.NumberOfBands;
                app.TabVidHandleWindow = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.TabVidAxesWindow);
                
                %some property thats a plot handle, used to plot ontop of the video feeds
                hold(app.TabVidAxesWindow, 'on');
                app.TabPlotHandle(1) = plot(app.TabVidAxesWindow, 0, 0, 'r+');
                app.TabPlotHandle(2) = plot(app.TabVidAxesWindow, 0, 0, 'g+'); %reachable
                
                %create 40 of these labels
                % 1 to 2 is the plot centroids
                % 3 to 43 is the letter labels
                % 43 to 83 is the edges
                % 83 to 123 is the edges
               
                for i = 1:40
                   app.TabPlotHandle(i + 2) = text(0, 0, "", 'FontSize', 11,'FontWeight', 'bold', 'Parent', app.TabVidAxesWindow); % Letters
                   app.TabPlotHandle(i + 42) = text(0, 0, "", 'FontSize', 11, 'FontWeight', 'bold', 'Parent', app.TabVidAxesWindow); % Orientation
                   app.TabPlotHandle(i + 82) = plot(app.TabVidAxesWindow, 0, 0, 'b'); % Edges
                end
                
                hold(app.TabVidAxesWindow, 'off');
            catch
                delete(app.tabFig);
                disp('Error in opening table video feed');
                return;
            end
            % Disp this video feed from the video tick
            app.TabVidWindow = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            AppClose(app);
        end

        % Button pushed function: OpenConveyorCameraFeedButton
        function OpenConveyorCameraFeedButtonPushed(app, event)
            % Prepare the video to be shown in the new figure
            app.conFig = uifigure('Name','Conveyor Camera Feed','CloseRequestFcn',@(fig, event)VidFigCloseReq(app,app.conFig,'Conveyor'),'Position',[50 50 800 600]);%
            app.ConVidAxesWindow = uiaxes(app.conFig,'Position',[10,10,780,580]);
            %try
                video_resolution1 = app.vidConveyor.VideoResolution;
                nbands1 = app.vidConveyor.NumberOfBands;
                app.ConVidHandleWindow = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.ConVidAxesWindow);
                hold(app.ConVidAxesWindow, 'on');
                
                app.ConPlotHandle(1) = plot(0, 0, 'r', 'Parent', app.ConVidAxesWindow);
                app.ConPlotHandle(2) = plot(0, 0, 'r', 'Parent', app.ConVidAxesWindow);
                app.ConPlotHandle(3) = plot(0, 0, 'r', 'Parent', app.ConVidAxesWindow);
                app.ConPlotHandle(4) = plot(0, 0, 'r', 'Parent', app.ConVidAxesWindow);

                app.ConPlotHandle(5) = text(0, 0, "", 'FontSize', 24,'Color','red', 'Parent', app.ConVidAxesWindow);             
                
                hold(app.ConVidAxesWindow, 'off');
            %catch
                %delete(app.conFig);
                %disp('Error in opening conveyor video feed');
                %return;
            %end
            % Disp this video feed from the video tick
            app.ConVidWindow = 1;
        end

        % Button pushed function: SetallDIOto0Button
        function SetallDIOto0ButtonPushed(app, event)
            % Request all DIO to 0
            [cmd, app.queue] = changeDIO('0000',app.queue);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.JointAngleTable = uitable(app.OverviewTab);
            app.JointAngleTable.ColumnName = {'Joints'; 'Current Angle'};
            app.JointAngleTable.RowName = {};
            app.CurrentJointAnglesLabel = uilabel(app.OverviewTab);
            app.CurrentJointAnglesLabel.Text = 'Current Joint Angles';
            app.EEPositionTable = uitable(app.OverviewTab);
            app.EEPositionTable.ColumnName = {'Position'; 'Value'};
            app.EEPositionTable.RowName = {};
            app.EEPositionLabel = uilabel(app.OverviewTab);
            app.EEPositionLabel.Text = 'EE Position';
            app.EEOrientationTable = uitable(app.OverviewTab);
            app.EEOrientationTable.ColumnName = {'Orientation'; 'Value'};
            app.EEOrientationTable.RowName = {};
            app.EEOrientationLabel = uilabel(app.OverviewTab);
            app.EEOrientationLabel.Text = 'EE Orientation';
            app.DIOStatesLabel = uilabel(app.OverviewTab);
            app.DIOStatesLabel.Text = 'DIO States';
            app.PumpButton = uibutton(app.OverviewTab, 'state');
            app.PumpButton.ValueChangedFcn = createCallbackFcn(app, @PumpButtonValueChanged, true);
            app.PumpButton.Text = 'Pump';
            app.VacuumLabel = uilabel(app.OverviewTab);
            app.VacuumLabel.Text = 'Vacuum';
            app.SolenoidButton = uibutton(app.OverviewTab, 'state');
            app.SolenoidButton.ValueChangedFcn = createCallbackFcn(app, @SolenoidButtonValueChanged, true);
            app.SolenoidButton.Text = 'Solenoid';
            app.ConveyorLabel = uilabel(app.OverviewTab);
            app.ConveyorLabel.Text = 'Conveyor';
            app.ConRunButton = uibutton(app.OverviewTab, 'state');
            app.ConRunButton.ValueChangedFcn = createCallbackFcn(app, @ConRunButtonValueChanged, true);
            app.ConRunButton.Text = 'Run';
            app.DirectionButtonGroup = uibuttongroup(app.OverviewTab);
            app.DirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DirectionButtonGroupSelectionChanged, true);
            app.DirectionButtonGroup.Title = 'Direction';
            app.IntocellButton = uitogglebutton(app.DirectionButtonGroup);
            app.IntocellButton.Text = 'In to cell';
            app.IntocellButton.Value = true;
            app.OutofcellButton = uitogglebutton(app.DirectionButtonGroup);
            app.OutofcellButton.Text = 'Out of cell';
            app.TabGroup2 = uitabgroup(app.OverviewTab);
            app.SentTab = uitab(app.TabGroup2);
            app.SentTab.Title = 'Sent';
            app.SentLog = uitextarea(app.SentTab);
            app.SentLog.Editable = 'off';
            app.ReceivedTab = uitab(app.TabGroup2);
            app.ReceivedTab.Title = 'Received';
            app.ReceivedLog = uitextarea(app.ReceivedTab);
            app.ReceivedLog.Editable = 'off';
            app.QueuedTab = uitab(app.TabGroup2);
            app.QueuedTab.Title = 'Queued';
            app.QueuedLog = uitextarea(app.QueuedTab);
            app.QueuedLog.Editable = 'off';
            app.MessageLogLabel = uilabel(app.OverviewTab);
            app.MessageLogLabel.Text = 'Message Log';
            app.RobotStatusLabel = uilabel(app.OverviewTab);
            app.RobotStatusLabel.Text = 'Robot Status';
            app.StatusLampLabel = uilabel(app.OverviewTab);
            app.StatusLampLabel.Text = 'Status';
            app.ConStatLamp = uilamp(app.OverviewTab);
            app.TabVidAxes = uiaxes(app.OverviewTab);
            title(app.TabVidAxes, 'Table Video')
            app.ConVidAxes = uiaxes(app.OverviewTab);
            title(app.ConVidAxes, 'Conveyor Video')
            app.PoseTab = uitab(app.TabGroup);
            app.PoseTab.Title = 'Pose';
            app.MovetoPositionPanel = uipanel(app.PoseTab);
            app.MovetoPositionPanel.Title = 'Move to Position';
            app.RelativetoHomeButtonGroup = uibuttongroup(app.MovetoPositionPanel);
            app.RelativetoHomeButtonGroup.Title = 'Relative to Home';
            app.TableButton = uitogglebutton(app.RelativetoHomeButtonGroup);
            app.TableButton.Text = 'Table';
            app.TableButton.Value = true;
            app.ConveyorButton = uitogglebutton(app.RelativetoHomeButtonGroup);
            app.ConveyorButton.Text = 'Conveyor';
            app.XEditFieldLabel = uilabel(app.MovetoPositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.MovetoPositionPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.MovetoPositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.MovetoPositionPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.MovetoPositionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.MovetoPositionPanel, 'numeric');
            app.RollEditFieldLabel = uilabel(app.MovetoPositionPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.MovetoPositionPanel, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.MovetoPositionPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.MovetoPositionPanel, 'numeric');
            app.YawEditFieldLabel = uilabel(app.MovetoPositionPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.MovetoPositionPanel, 'numeric');
            app.EndEffectorOrientationOnlySwitchLabel = uilabel(app.MovetoPositionPanel);
            app.EndEffectorOrientationOnlySwitchLabel.Text = 'End Effector Orientation Only';
            app.EndEffOriOnlySwitch = uiswitch(app.MovetoPositionPanel, 'slider');
            app.EndEffOriOnlySwitch.ValueChangedFcn = createCallbackFcn(app, @EndEffOriOnlySwitchValueChanged, true);
            app.SendPositionCommandButton = uibutton(app.MovetoPositionPanel, 'push');
            app.SendPositionCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendPositionCommandButtonPushed, true);
            app.SendPositionCommandButton.Text = 'Send Position Command';
            app.MovetoPosePanel = uipanel(app.PoseTab);
            app.MovetoPosePanel.Title = 'Move to Pose';
            app.Joint1EditFieldLabel = uilabel(app.MovetoPosePanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.MovetoPosePanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.MovetoPosePanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.MovetoPosePanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.MovetoPosePanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.MovetoPosePanel, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.MovetoPosePanel);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.MovetoPosePanel, 'numeric');
            app.Joint5EditFieldLabel = uilabel(app.MovetoPosePanel);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.MovetoPosePanel, 'numeric');
            app.Joint6EditFieldLabel = uilabel(app.MovetoPosePanel);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.MovetoPosePanel, 'numeric');
            app.SendPoseCommandButton = uibutton(app.MovetoPosePanel, 'push');
            app.SendPoseCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendPoseCommandButtonPushed, true);
            app.SendPoseCommandButton.Text = 'Send Pose Command';
            app.SpeedDropDownLabel = uilabel(app.PoseTab);
            app.SpeedDropDownLabel.Text = 'Speed';
            app.SpeedDropDown = uidropdown(app.PoseTab);
            app.SpeedDropDown.Items = {'100%', '75%', '50%', '25%'};
            app.SpeedDropDown.Value = '100%';
            app.JoggingTab = uitab(app.TabGroup);
            app.JoggingTab.Title = 'Jogging';
            app.JogLinearPanel = uipanel(app.JoggingTab);
            app.JogLinearPanel.Title = 'Jog Linear';
            app.RelativetoFrameButtonGroup = uibuttongroup(app.JogLinearPanel);
            app.RelativetoFrameButtonGroup.Title = 'Relative to Frame';
            app.BaseButton = uitogglebutton(app.RelativetoFrameButtonGroup);
            app.BaseButton.Text = 'Base';
            app.BaseButton.Value = true;
            app.EndEffectorButton = uitogglebutton(app.RelativetoFrameButtonGroup);
            app.EndEffectorButton.Text = 'End Effector';
            app.AxisButtonGroup = uibuttongroup(app.JogLinearPanel);
            app.AxisButtonGroup.Title = 'Axis';
            app.XButton = uitogglebutton(app.AxisButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uitogglebutton(app.AxisButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uitogglebutton(app.AxisButtonGroup);
            app.ZButton.Text = 'Z';
            app.JogButton = uibutton(app.JogLinearPanel, 'push');
            app.JogButton.Text = 'Jog';
            app.JogJointPanel = uipanel(app.JoggingTab);
            app.JogJointPanel.Title = 'Jog Joint';
            app.JointButtonGroup = uibuttongroup(app.JogJointPanel);
            app.JointButtonGroup.Title = 'Joint';
            app.Button = uitogglebutton(app.JointButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.JointButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.JointButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.JointButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uitogglebutton(app.JointButtonGroup);
            app.Button_5.Text = '5';
            app.Button_6 = uitogglebutton(app.JointButtonGroup);
            app.Button_6.Text = '6';
            app.JogButton_2 = uibutton(app.JogJointPanel, 'push');
            app.JogButton_2.Text = 'Jog';
            app.VideoTab = uitab(app.TabGroup);
            app.VideoTab.Title = 'Video';
            app.OpenTableCameraFeedButton = uibutton(app.VideoTab, 'push');
            app.OpenTableCameraFeedButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTableCameraFeedButtonPushed, true);
            app.OpenTableCameraFeedButton.Text = 'Open Table Camera Feed';
            app.OpenConveyorCameraFeedButton = uibutton(app.VideoTab, 'push');
            app.OpenConveyorCameraFeedButton.ButtonPushedFcn = createCallbackFcn(app, @OpenConveyorCameraFeedButtonPushed, true);
            app.OpenConveyorCameraFeedButton.Text = 'Open Conveyor Camera Feed';
            app.ShutdownTab = uitab(app.TabGroup);
            app.ShutdownTab.Title = 'Shutdown';
            app.MovetohomeButton = uibutton(app.ShutdownTab, 'push');
            app.MovetohomeButton.Text = 'Move to home';
            app.ShutdownProcedureLabel = uilabel(app.ShutdownTab);
            app.ShutdownProcedureLabel.Text = 'Shutdown Procedure';
            app.SetallDIOto0Button = uibutton(app.ShutdownTab, 'push');
            app.SetallDIOto0Button.ButtonPushedFcn = createCallbackFcn(app, @SetallDIOto0ButtonPushed, true);
            app.SetallDIOto0Button.Text = 'Set all DIO to 0';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.MainfunctiontickLampLabel = uilabel(app.DebugTab);
            app.MainfunctiontickLampLabel.Text = 'Main function tick';
            app.TickLamp = uilamp(app.DebugTab);
            app.VideofunctiontickLampLabel = uilabel(app.DebugTab);
            app.VideofunctiontickLampLabel.Text = 'Video function tick';
            app.VideoTickLamp = uilamp(app.DebugTab);
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.ErrorStateText = uieditfield(app.UIFigure, 'text');
            app.ErrorStateText.Editable = 'off';
            app.ERRORLampLabel = uilabel(app.UIFigure);
            app.ERRORLampLabel.Text = 'ERROR';
            app.ERRORLamp = uilamp(app.UIFigure);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Assignment2GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end